classdef projectapp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        PipeFlowUIFigure                matlab.ui.Figure
        PipelengthLabel_3               matlab.ui.control.Label
        HeadlossLabel_3                 matlab.ui.control.Label
        FullyturbulentflowCheckBox_3    matlab.ui.control.CheckBox
        Re_3                            matlab.ui.control.NumericEditField
        PipeLength_3                    matlab.ui.control.NumericEditField
        HeadLoss_3                      matlab.ui.control.NumericEditField
        DropDownFlow_3                  matlab.ui.control.DropDown
        mLabel_3                        matlab.ui.control.Label
        mLabel_2_3                      matlab.ui.control.Label
        VorQorM_3                       matlab.ui.control.NumericEditField
        unitLabel_3                     matlab.ui.control.Label
        mmLabel_3                       matlab.ui.control.Label
        DensityLabel_3                  matlab.ui.control.Label
        Density_3                       matlab.ui.control.NumericEditField
        kgm3Label_3                     matlab.ui.control.Label
        DropDown_3                      matlab.ui.control.DropDown
        Viscosity_3                     matlab.ui.control.NumericEditField
        ViscosityLabel_3                matlab.ui.control.Label
        FlowLabel_3                     matlab.ui.control.Label
        FindHeadLossButton              matlab.ui.control.Button
        FindFlowRateButton              matlab.ui.control.Button
        FindFlowDiameterButton          matlab.ui.control.Button
        PipelengthLabel_2               matlab.ui.control.Label
        HeadlossLabel_2                 matlab.ui.control.Label
        FullyturbulentflowCheckBox_2    matlab.ui.control.CheckBox
        Ks_2                            matlab.ui.control.NumericEditField
        PipeLength_2                    matlab.ui.control.NumericEditField
        HeadLoss_2                      matlab.ui.control.NumericEditField
        mLabel_2                        matlab.ui.control.Label
        mLabel_2_2                      matlab.ui.control.Label
        mmLabel_2                       matlab.ui.control.Label
        DensityLabel_2                  matlab.ui.control.Label
        Density_2                       matlab.ui.control.NumericEditField
        kgm3Label_2                     matlab.ui.control.Label
        DropDown_2                      matlab.ui.control.DropDown
        Viscosity_2                     matlab.ui.control.NumericEditField
        ViscosityLabel_2                matlab.ui.control.Label
        CircularprofileCheckBox         matlab.ui.control.CheckBox
        Area_2                          matlab.ui.control.NumericEditField
        FlowdiameterLabel               matlab.ui.control.Label
        Diameter_2                      matlab.ui.control.NumericEditField
        mLabel_3_2                      matlab.ui.control.Label
        Perimeter_2                     matlab.ui.control.NumericEditField
        AreaLabel_2                     matlab.ui.control.Label
        WettedperimeterLabel_2          matlab.ui.control.Label
        mSqLabel_2                      matlab.ui.control.Label
        mLabel_4_2                      matlab.ui.control.Label
        EquivalentsandroughnessLabel_2  matlab.ui.control.Label
        FlowLabel_2                     matlab.ui.control.Label
        PipelengthLabel_1               matlab.ui.control.Label
        FullyturbulentflowCheckBox_1    matlab.ui.control.CheckBox
        Ks_1                            matlab.ui.control.NumericEditField
        PipeLength_1                    matlab.ui.control.NumericEditField
        mLabel_1                        matlab.ui.control.Label
        mmLabel_1                       matlab.ui.control.Label
        DensityLabel_1                  matlab.ui.control.Label
        Density_1                       matlab.ui.control.NumericEditField
        kgm3Label_1                     matlab.ui.control.Label
        DropDown_1                      matlab.ui.control.DropDown
        Viscosity_1                     matlab.ui.control.NumericEditField
        ViscosityLabel_1                matlab.ui.control.Label
        CircularProfileCheckBox_1       matlab.ui.control.CheckBox
        Area_1                          matlab.ui.control.NumericEditField
        mLabel_3_1                      matlab.ui.control.Label
        Perimeter_1                     matlab.ui.control.NumericEditField
        AreaLabel_1                     matlab.ui.control.Label
        WettedperimeterLabel_1          matlab.ui.control.Label
        mSqLabel_1                      matlab.ui.control.Label
        mLabel_4_1                      matlab.ui.control.Label
        FlowDiameterLabel_1             matlab.ui.control.Label
        Diameter_1                      matlab.ui.control.NumericEditField
        EquivalentsandroughnessLabel_1  matlab.ui.control.Label
        FlowLabel_1                     matlab.ui.control.Label
        DropDownFlow_1                  matlab.ui.control.DropDown
        VorQorM_1                       matlab.ui.control.NumericEditField
        unitLabel_1                     matlab.ui.control.Label
        ReynoldsnumberCheckBox_3        matlab.ui.control.CheckBox
        Ks_3                            matlab.ui.control.NumericEditField
        EquivalentsandroughnessLabel_3  matlab.ui.control.Label
        Solve_1                         matlab.ui.control.Button
        Solve_2                         matlab.ui.control.Button
        Solve_3                         matlab.ui.control.Button
        Result_1                        matlab.ui.control.NumericEditField
        Result_2                        matlab.ui.control.NumericEditField
        Result_3                        matlab.ui.control.NumericEditField
        mLabel_4                        matlab.ui.control.Label
        m3sLabel                        matlab.ui.control.Label
        MinorHeadlossCoefficientLabel   matlab.ui.control.Label
        ComponentsListBoxLabel          matlab.ui.control.Label
        Components                      matlab.ui.control.ListBox
        Types                           matlab.ui.control.ListBox
        TypeLabel                       matlab.ui.control.Label
        KLabel                          matlab.ui.control.Label
        TypeList                        matlab.ui.container.Tree
        PipeenteranceNode               matlab.ui.container.TreeNode
        ContractionNode                 matlab.ui.container.TreeNode
        ExpansionNode                   matlab.ui.container.TreeNode
        degreemiterbendNode             matlab.ui.container.TreeNode
        degreesmoothbendNode            matlab.ui.container.TreeNode
        ThreadedpipefittingsNode        matlab.ui.container.TreeNode
        GlobevalvewideopenNode          matlab.ui.container.TreeNode
        AnglevalvewideopenNode          matlab.ui.container.TreeNode
        GatevalvewideopenNode           matlab.ui.container.TreeNode
        GatevalvehalfopenNode           matlab.ui.container.TreeNode
        ReturnbendNode                  matlab.ui.container.TreeNode
        degreeelbowNode                 matlab.ui.container.TreeNode
        degreeelbowNode_2               matlab.ui.container.TreeNode
        TeeNode                         matlab.ui.container.TreeNode
        StraightthroughflowNode         matlab.ui.container.TreeNode
        SideoutletflowNode              matlab.ui.container.TreeNode
        KList                           matlab.ui.control.ListBox
        TotalHeadlossCoefficientKEditFieldLabel  matlab.ui.control.Label
        TotalK                          matlab.ui.control.NumericEditField
        ComponentSelectionLabel         matlab.ui.control.Label
        TypeLabel_2                     matlab.ui.control.Label
        rdEditFieldLabel                matlab.ui.control.Label
        rd                              matlab.ui.control.NumericEditField
        rEditFieldLabel                 matlab.ui.control.Label
        r                               matlab.ui.control.NumericEditField
        dEditFieldLabel                 matlab.ui.control.Label
        d                               matlab.ui.control.NumericEditField
        CheckBox                        matlab.ui.control.CheckBox
        CheckBox_2                      matlab.ui.control.CheckBox
        D1D2EditFieldLabel              matlab.ui.control.Label
        D1EditFieldLabel                matlab.ui.control.Label
        D1                              matlab.ui.control.NumericEditField
        D2EditFieldLabel                matlab.ui.control.Label
        D2                              matlab.ui.control.NumericEditField
        CheckBox_3                      matlab.ui.control.CheckBox
        KLabel_2                        matlab.ui.control.Label
        K                               matlab.ui.control.NumericEditField
        ComponentName                   matlab.ui.control.EditField
        AddButton                       matlab.ui.control.Button
        Kh                              matlab.ui.control.NumericEditField
        ThetaLabel                      matlab.ui.control.Label
        Theta                           matlab.ui.control.DropDown
        D1D2                            matlab.ui.control.NumericEditField
        img_PipeEnterance               matlab.ui.control.Image
        img_Contraction                 matlab.ui.control.Image
        img_Expansion                   matlab.ui.control.Image
        img_SmoothBend                  matlab.ui.control.Image
        img_General                     matlab.ui.control.Image
        ResetButton                     matlab.ui.control.Button
        CheckBoxL                       matlab.ui.control.CheckBox
        RFlowLabel_3                    matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: FullyturbulentflowCheckBox_3
        function FullyturbulentflowCheckBox_3ValueChanged(app, event)
            value = app.FullyturbulentflowCheckBox_3.Value;
            if value
                app.FlowLabel_3.Enable = 'off';
                app.Re_3.Enable = 'off';
                app.ReynoldsnumberCheckBox_3.Enable='off';
                if app.DropDownFlow_3~="Mass flow rate"
                    app.Density_3.Enable='off';
                    app.DensityLabel_3.Enable='off';
                    app.kgm3Label_3.Enable='off';
                end
                app.DropDown_3.Enable='off';
                app.Viscosity_3.Enable='off';
                app.ViscosityLabel_3.Enable='off';
                if app.DropDownFlow_3.Value=="Mass flow rate"
                    app.Density_3.Enable='on';
                    app.DensityLabel_3.Enable='on';
                    app.kgm3Label_3.Enable='on';
                end
            else
                app.ReynoldsnumberCheckBox_3.Enable='on';
                Rcheck = app.ReynoldsnumberCheckBox_3.Value;
                if Rcheck
                    if app.DropDown_3.Value=="Dynamic viscosity"
                        app.Density_3.Enable='on';
                        app.DensityLabel_3.Enable='on';
                        app.kgm3Label_3.Enable='on';
                    end
                    app.DropDown_3.Enable='off';
                    app.Viscosity_3.Enable='off';
                    app.ViscosityLabel_3.Enable='off';
                    app.Re_3.Enable='on';
                    app.FlowLabel_3.Enable='on';
                else
                    app.Re_3.Enable='off';
                    app.FlowLabel_3.Enable='off';
                    if app.DropDownFlow_3.Value~="Mass flow rate"
                        app.Density_3.Enable='off';
                        app.DensityLabel_3.Enable='off';
                        app.kgm3Label_3.Enable='off';
                    end
                    app.DropDown_3.Enable='off';
                    app.Viscosity_3.Enable='off';
                    app.ViscosityLabel_3.Enable='off';
                    app.DropDown_3.Enable='on';
                    app.Viscosity_3.Enable='on';
                    app.ViscosityLabel_3.Enable='on';
                end
                if app.DropDownFlow_3=="Mass flow rate"
                    app.Density_3.Enable='on';
                    app.DensityLabel_3.Enable='on';
                    app.kgm3Label_3.Enable='on';
                end
                if app.DropDown_3.Value=="Dynamic viscosity"
                    app.Density_3.Enable='on';
                    app.DensityLabel_3.Enable='on';
                    app.kgm3Label_3.Enable='on';
                end
                
            end
            
        end

        % Callback function
        function DropDownKsorRe_3ValueChanged(app, event)
            value = app.DropDownKsorRe_3.Value;
            if value~="Reynold's number"
                app.DropDown_3.Enable='on';
                app.Viscosity_3.Enable='on';
                app.ViscosityLabel_3.Enable='on';
            else
                if value=="Reynold's number"
                    if app.DropDownFlow_3.Value~="Mass flow rate"
                        app.Density_3.Enable='off';
                        app.DensityLabel_3.Enable='off';
                        app.kgm3Label_3.Enable='off';
                    end
                end
                app.DropDown_3.Enable='off';
                app.Viscosity_3.Enable='off';
                app.ViscosityLabel_3.Enable='off';
            end
            if value=="Equivalent sand roughness"
                app.mmLabel_3.Visible='on';
                app.FlowLabel_3.Visible='off';
                app.Density_3.Enable='on';
                app.DensityLabel_3.Enable='on';
                app.kgm3Label_3.Enable='on';
            else
                app.mmLabel_3.Visible='off';
                app.FlowLabel_3.Visible='on';
            end
        end

        % Value changed function: DropDownFlow_3
        function DropDownFlow_3ValueChanged(app, event)
            value = app.DropDownFlow_3.Value;
            if value == "Volume flow rate"
                app.unitLabel_3.Text="m^3/s" ;
                if app.DropDown_3.Value~="Dynamic viscosity"
                    app.Density_3.Enable='off';
                    app.DensityLabel_3.Enable='off';
                    app.kgm3Label_3.Enable='off';
                end
                %                 if app.ReynoldsnumberCheckBox_3.Value
                %                 else
                %                     app.Density_3.Enable='off';
                %                     app.DensityLabel_3.Enable='off';
                %                     app.kgm3Label_3.Enable='off';
                %                 end
            end
            if value == "Mass flow rate"
                app.unitLabel_3.Text="kg/s";
                app.Density_3.Enable='on';
                app.DensityLabel_3.Enable='on';
                app.kgm3Label_3.Enable='on';
            end
            if value == "Flow velocity"
                app.unitLabel_3.Text="m/s";
                if app.DropDown_3.Value~="Dynamic viscosity"
                    app.Density_3.Enable='off';
                    app.DensityLabel_3.Enable='off';
                    app.kgm3Label_3.Enable='off';
                end
                %                 if app.ReynoldsnumberCheckBox_3.Value && app.DropDown_1.Value~="Dynamic viscosity"
                %                 else
                %                     app.Density_3.Enable='off';
                %                     app.DensityLabel_3.Enable='off';
                %                     app.kgm3Label_3.Enable='off';
                %                 end
            end
            
            
        end

        % Value changed function: DropDown_3
        function DropDown_3ValueChanged(app, event)
            value = app.DropDown_3.Value;
            if value== "Dynamic viscosity"
                app.ViscosityLabel_3.Text="Pa.s";
                app.Density_3.Enable='on';
                app.DensityLabel_3.Enable='on';
                app.kgm3Label_3.Enable='on';
            end
            if value== "Kinematic viscosity"
                app.ViscosityLabel_3.Text="m^2/s";
                if app.DropDownFlow_3.Value~="Mass flow rate"
                app.Density_3.Enable='off';
                app.DensityLabel_3.Enable='off';
                app.kgm3Label_3.Enable='off';
                end
            end
        end

        % Value changed function: Re_3
        function Re_3ValueChanged(app, event)
            value = app.Re_3.Value;
            if value<2000
                app.RFlowLabel_3.Text="Laminar flow";
                app.CheckBoxL.Enable='on'; [app.Ks_1.Enable,app.Ks_2.Enable,app.Ks_3.Enable,app.mmLabel_1.Enable,app.mmLabel_2.Enable,app.mmLabel_3.Enable,app.EquivalentsandroughnessLabel_1.Enable,app.EquivalentsandroughnessLabel_2.Enable,app.EquivalentsandroughnessLabel_3.Enable]=deal('off');
                
            elseif 2000<=value && value<3000
               app.RFlowLabel_3.Text="Transitional flow";
               app.CheckBoxL.Enable='off';
               app.CheckBoxL.Value=false;[app.Ks_1.Enable,app.Ks_2.Enable,app.Ks_3.Enable,app.mmLabel_1.Enable,app.mmLabel_2.Enable,app.mmLabel_3.Enable,app.EquivalentsandroughnessLabel_1.Enable,app.EquivalentsandroughnessLabel_2.Enable,app.EquivalentsandroughnessLabel_3.Enable]=deal('on');
            elseif value>=3000
                app.RFlowLabel_3.Text="Turbulent flow";
                app.CheckBoxL.Enable='off';
               app.CheckBoxL.Value=false;[app.Ks_1.Enable,app.Ks_2.Enable,app.Ks_3.Enable,app.mmLabel_1.Enable,app.mmLabel_2.Enable,app.mmLabel_3.Enable,app.EquivalentsandroughnessLabel_1.Enable,app.EquivalentsandroughnessLabel_2.Enable,app.EquivalentsandroughnessLabel_3.Enable]=deal('on');
            end
            if value==0
                app.RFlowLabel_3.Text="";
            end
        end

        % Button pushed function: FindFlowDiameterButton
        function FindFlowDiameterButtonPushed(app, event)
            [app.mLabel_4.Visible,app.PipelengthLabel_3.Visible,app.Solve_3.Visible,app.Result_3.Visible,app. EquivalentsandroughnessLabel_3.Visible,app.Ks_3.Visible,app. ReynoldsnumberCheckBox_3.Visible,app.HeadlossLabel_3.Visible,app.FullyturbulentflowCheckBox_3.Visible,app.Re_3.Visible,app.PipeLength_3.Visible,app.HeadLoss_3.Visible,app.DropDownFlow_3.Visible,app.mLabel_3.Visible,app.mLabel_2_3.Visible,app.VorQorM_3.Visible,app.unitLabel_3.Visible,app.mmLabel_3.Visible,app.DensityLabel_3.Visible,app.Density_3.Visible,app.kgm3Label_3.Visible,app.DropDown_3.Visible,app.Viscosity_3.Visible,app.ViscosityLabel_3.Visible]=deal('on');
            [app.m3sLabel.Visible,app.PipelengthLabel_1.Visible,app.Solve_1.Visible,app.Result_1.Visible,app. DropDownFlow_1.Visible,app. VorQorM_1.Visible,app. unitLabel_1.Visible,app.FlowLabel_1.Visible,app.FullyturbulentflowCheckBox_1.Visible,app.Ks_1.Visible,app.PipeLength_1.Visible,app.mLabel_1.Visible,app.mmLabel_1.Visible,app.DensityLabel_1.Visible,app.Density_1.Visible,app.kgm3Label_1.Visible,app.DropDown_1.Visible,app.Viscosity_1.Visible,app.ViscosityLabel_1.Visible,app.CircularProfileCheckBox_1.Visible,app.Area_1.Visible,app.Diameter_1.Visible,app.mLabel_3_1.Visible,app.Perimeter_1.Visible,app.AreaLabel_1.Visible,app.WettedperimeterLabel_1.Visible,app.mSqLabel_1.Visible,app.mLabel_4_1.Visible,app.EquivalentsandroughnessLabel_1.Visible,app.FlowDiameterLabel_1.Visible]=deal('off');
            [app.PipelengthLabel_2.Visible,app.Solve_2.Visible,app.Result_2.Visible,app.FlowLabel_2.Visible,app.HeadlossLabel_2.Visible,app.FullyturbulentflowCheckBox_2.Visible,app.Ks_2.Visible,app.PipeLength_2.Visible,app.HeadLoss_2.Visible,app.mLabel_2.Visible,app.mLabel_2_2.Visible,app.mmLabel_2.Visible,app.DensityLabel_2.Visible,app.Density_2.Visible,app.kgm3Label_2.Visible,app.DropDown_2.Visible,app.Viscosity_2.Visible,app.ViscosityLabel_2.Visible,app.CircularprofileCheckBox.Visible,app.Area_2.Visible,app.Diameter_2.Visible,app.mLabel_3_2.Visible,app.Perimeter_2.Visible,app.AreaLabel_2.Visible,app.WettedperimeterLabel_2.Visible,app.mSqLabel_2.Visible,app.mLabel_4_2.Visible,app.EquivalentsandroughnessLabel_2.Visible,app.FlowdiameterLabel.Visible]=deal('off');
            app.FindHeadLossButton.BackgroundColor=[0.90,0.90,0.90];
            app.FindFlowDiameterButton.BackgroundColor=[0.4471 0.949 0.4471];
            app.FindFlowRateButton.BackgroundColor=[0.90,0.90,0.90];
        end

        % Button pushed function: FindFlowRateButton
        function FindFlowRateButtonPushed(app, event)
            [app.m3sLabel.Visible,app.PipelengthLabel_2.Visible,app.Solve_2.Visible,app.Result_2.Visible,app.HeadlossLabel_2.Visible,app.FullyturbulentflowCheckBox_2.Visible,app.Ks_2.Visible,app.PipeLength_2.Visible,app.HeadLoss_2.Visible,app.mLabel_2.Visible,app.mLabel_2_2.Visible,app.mmLabel_2.Visible,app.DensityLabel_2.Visible,app.Density_2.Visible,app.kgm3Label_2.Visible,app.DropDown_2.Visible,app.Viscosity_2.Visible,app.ViscosityLabel_2.Visible,app.CircularprofileCheckBox.Visible,app.Area_2.Visible,app.Diameter_2.Visible,app.mLabel_3_2.Visible,app.Perimeter_2.Visible,app.AreaLabel_2.Visible,app.WettedperimeterLabel_2.Visible,app.mSqLabel_2.Visible,app.mLabel_4_2.Visible,app.EquivalentsandroughnessLabel_2.Visible,app.FlowdiameterLabel.Visible]=deal('on');
            [app.mLabel_4.Visible,app.PipelengthLabel_1.Visible,app.Solve_1.Visible,app.Result_1.Visible,app.DropDownFlow_1.Visible,app. VorQorM_1.Visible,app. unitLabel_1.Visible,app.FlowLabel_1.Visible,app.FullyturbulentflowCheckBox_1.Visible,app.Ks_1.Visible,app.PipeLength_1.Visible,app.mLabel_1.Visible,app.mmLabel_1.Visible,app.DensityLabel_1.Visible,app.Density_1.Visible,app.kgm3Label_1.Visible,app.DropDown_1.Visible,app.Viscosity_1.Visible,app.ViscosityLabel_1.Visible,app.CircularProfileCheckBox_1.Visible,app.Area_1.Visible,app.Diameter_1.Visible,app.mLabel_3_1.Visible,app.Perimeter_1.Visible,app.AreaLabel_1.Visible,app.WettedperimeterLabel_1.Visible,app.mSqLabel_1.Visible,app.mLabel_4_1.Visible,app.EquivalentsandroughnessLabel_1.Visible,app.FlowDiameterLabel_1.Visible]=deal('off');
            [app.PipelengthLabel_3.Visible,app.Solve_3.Visible,app.Result_3.Visible,app. EquivalentsandroughnessLabel_3.Visible,app.Ks_3.Visible,app. ReynoldsnumberCheckBox_3.Visible,app.FlowLabel_3.Visible,app.HeadlossLabel_3.Visible,app.FullyturbulentflowCheckBox_3.Visible,app.Re_3.Visible,app.PipeLength_3.Visible,app.HeadLoss_3.Visible,app.DropDownFlow_3.Visible,app.mLabel_3.Visible,app.mLabel_2_3.Visible,app.VorQorM_3.Visible,app.unitLabel_3.Visible,app.mmLabel_3.Visible,app.DensityLabel_3.Visible,app.Density_3.Visible,app.kgm3Label_3.Visible,app.DropDown_3.Visible,app.Viscosity_3.Visible,app.ViscosityLabel_3.Visible,app.FlowLabel_3.Visible]=deal('off');
            app.FindHeadLossButton.BackgroundColor=[0.90,0.90,0.90];
            app.FindFlowDiameterButton.BackgroundColor=[0.90,0.90,0.90];
            app.FindFlowRateButton.BackgroundColor=[0.4471 0.949 0.4471];
            if app.DropDown_2.Value=="Reynold's number"
                app.ViscosityLabel_2.Visible='off';
                app.FlowLabel_2.Visible='on';
                app.mmLabel_2.Visible='off';
            end
        end

        % Button pushed function: FindHeadLossButton
        function FindHeadLossButtonPushed(app, event)
            [app.mLabel_4.Visible,app.PipelengthLabel_1.Visible,app.Solve_1.Visible,app.Result_1.Visible,app.DropDownFlow_1.Visible,app. VorQorM_1.Visible,app. unitLabel_1.Visible,app.FlowLabel_1.Visible,app.FullyturbulentflowCheckBox_1.Visible,app.Ks_1.Visible,app.PipeLength_1.Visible,app.mLabel_1.Visible,app.mmLabel_1.Visible,app.DensityLabel_1.Visible,app.Density_1.Visible,app.kgm3Label_1.Visible,app.DropDown_1.Visible,app.Viscosity_1.Visible,app.ViscosityLabel_1.Visible,app.CircularProfileCheckBox_1.Visible,app.Area_1.Visible,app.Diameter_1.Visible,app.mLabel_3_1.Visible,app.Perimeter_1.Visible,app.AreaLabel_1.Visible,app.WettedperimeterLabel_1.Visible,app.mSqLabel_1.Visible,app.mLabel_4_1.Visible,app.EquivalentsandroughnessLabel_1.Visible,app.FlowDiameterLabel_1.Visible]=deal('on');
            [app.m3sLabel.Visible,app.PipelengthLabel_2.Visible,app.Solve_2.Visible,app.Result_2.Visible,app.FlowLabel_2.Visible,app.HeadlossLabel_2.Visible,app.FullyturbulentflowCheckBox_2.Visible,app.Ks_2.Visible,app.PipeLength_2.Visible,app.HeadLoss_2.Visible,app.mLabel_2.Visible,app.mLabel_2_2.Visible,app.mmLabel_2.Visible,app.DensityLabel_2.Visible,app.Density_2.Visible,app.kgm3Label_2.Visible,app.DropDown_2.Visible,app.Viscosity_2.Visible,app.ViscosityLabel_2.Visible,app.CircularprofileCheckBox.Visible,app.Area_2.Visible,app.Diameter_2.Visible,app.mLabel_3_2.Visible,app.Perimeter_2.Visible,app.AreaLabel_2.Visible,app.WettedperimeterLabel_2.Visible,app.mSqLabel_2.Visible,app.mLabel_4_2.Visible,app.EquivalentsandroughnessLabel_2.Visible,app.FlowdiameterLabel.Visible]=deal('off');
            [app.PipelengthLabel_3.Visible,app.Solve_3.Visible,app.Result_3.Visible,app. EquivalentsandroughnessLabel_3.Visible,app.Ks_3.Visible,app. ReynoldsnumberCheckBox_3.Visible,app.FlowLabel_3.Visible,app.HeadlossLabel_3.Visible,app.FullyturbulentflowCheckBox_3.Visible,app.Re_3.Visible,app.PipeLength_3.Visible,app.HeadLoss_3.Visible,app.DropDownFlow_3.Visible,app.mLabel_3.Visible,app.mLabel_2_3.Visible,app.VorQorM_3.Visible,app.unitLabel_3.Visible,app.mmLabel_3.Visible,app.DensityLabel_3.Visible,app.Density_3.Visible,app.kgm3Label_3.Visible,app.DropDown_3.Visible,app.Viscosity_3.Visible,app.ViscosityLabel_3.Visible]=deal('off');
            app.FindHeadLossButton.BackgroundColor=[0.4471 0.949 0.4471];
            app.FindFlowDiameterButton.BackgroundColor=[0.90,0.90,0.90];
            app.FindFlowRateButton.BackgroundColor=[0.90,0.90,0.90];
            if app.DropDown_1.Value=="Reynold's number"
                app.ViscosityLabel_1.Visible='off';
                app.FlowLabel_1.Visible='on';
                app.mmLabel_1.Visible='off';
            else
                app.FlowLabel_1.Visible='off';
            end
        end

        % Value changed function: CircularprofileCheckBox
        function CircularprofileCheckBoxValueChanged(app, event)
            value = app.CircularprofileCheckBox.Value;
            if value
                app.AreaLabel_2.Enable='off';
                app.Area_2.Enable='off';
                app.mSqLabel_2.Enable='off';
                app.WettedperimeterLabel_2.Enable='off';
                app.Perimeter_2.Enable='off';
                app.mLabel_4_2.Enable='off';
                app.Diameter_2.Enable='on';
                app.mLabel_3_2.Enable='on';
            else
               app.AreaLabel_2.Enable='on';
                app.Area_2.Enable='on';
                app.mSqLabel_2.Enable='on';
                app.WettedperimeterLabel_2.Enable='on';
                app.Perimeter_2.Enable='on';
                app.mLabel_4_2.Enable='on';
                app.Diameter_2.Enable='off';
                app.mLabel_3_2.Enable='off';
            end
        end

        % Value changed function: FullyturbulentflowCheckBox_2
        function FullyturbulentflowCheckBox_2ValueChanged(app, event)
            value = app.FullyturbulentflowCheckBox_2.Value;
            if value
                app.DensityLabel_2.Enable='off';
                app.Density_2.Enable='off';
                app.DropDown_2.Enable='off';
                app.ViscosityLabel_2.Enable='off';
                app.Viscosity_2.Enable='off';
                app.kgm3Label_2.Enable='off';
            else
                if app.DropDown_2.Value=="Dynamic viscosity"
                    app.DensityLabel_2.Enable='on';
                    app.Density_2.Enable='on';
                    app.kgm3Label_2.Enable='on';
                end
                app.DropDown_2.Enable='on';
                app.ViscosityLabel_2.Enable='on';
                app.Viscosity_2.Enable='on';
            end
            
        end

        % Value changed function: Area_2
        function Area_2ValueChanged(app, event)
            area = app.Area_2.Value;
            perimeter=app.Perimeter_2.Value;
            if area~=0 && perimeter~=0
            app.Diameter_2.Value=4*(area)/(perimeter);
            end
        end

        % Value changed function: Perimeter_2
        function Perimeter_2ValueChanged(app, event)
            area = app.Area_2.Value;
            perimeter=app.Perimeter_2.Value;
            if area~=0 && perimeter~=0
            app.Diameter_2.Value=4*(area)/(perimeter);
            end
        end

        % Value changed function: DropDown_2
        function DropDown_2ValueChanged(app, event)
            value = app.DropDown_2.Value;
            if value=="Kinematic viscosity"
                app.CheckBoxL.Enable='off';
                app.CheckBoxL.Value=false;[app.Ks_1.Enable,app.Ks_2.Enable,app.Ks_3.Enable,app.mmLabel_1.Enable,app.mmLabel_2.Enable,app.mmLabel_3.Enable,app.EquivalentsandroughnessLabel_1.Enable,app.EquivalentsandroughnessLabel_2.Enable,app.EquivalentsandroughnessLabel_3.Enable]=deal('on');
                app.ViscosityLabel_2.Text="m^2/s";
                app.ViscosityLabel_2.Visible='on';
                app.FlowLabel_2.Enable='off';
                app.FlowLabel_2.Visible='off';
                app.DensityLabel_2.Enable='off';
                app.Density_2.Enable='off';
                app.kgm3Label_2.Enable='off';
            else
                if value=="Dynamic viscosity"
                    app.CheckBoxL.Enable='off';
                    app.CheckBoxL.Value=false;[app.Ks_1.Enable,app.Ks_2.Enable,app.Ks_3.Enable,app.mmLabel_1.Enable,app.mmLabel_2.Enable,app.mmLabel_3.Enable,app.EquivalentsandroughnessLabel_1.Enable,app.EquivalentsandroughnessLabel_2.Enable,app.EquivalentsandroughnessLabel_3.Enable]=deal('on');
                    app.ViscosityLabel_2.Text="Pa.s";
                    app.ViscosityLabel_2.Visible='on';
                    app.FlowLabel_2.Enable='off';
                    app.FlowLabel_2.Visible='off';
                else
                    app.ViscosityLabel_2.Visible='off';
                    app.FlowLabel_2.Enable='on';
                    app.FlowLabel_2.Visible='on';
                end
                app.DensityLabel_2.Enable='on';
                app.Density_2.Enable='on';
                app.kgm3Label_2.Enable='on';
            end
            if value =="Reynold's number"
                if app.Viscosity_2<2000
                    app.CheckBoxL.Enable='on'; [app.Ks_1.Enable,app.Ks_2.Enable,app.Ks_3.Enable,app.mmLabel_1.Enable,app.mmLabel_2.Enable,app.mmLabel_3.Enable,app.EquivalentsandroughnessLabel_1.Enable,app.EquivalentsandroughnessLabel_2.Enable,app.EquivalentsandroughnessLabel_3.Enable]=deal('off');
                end
                app.DensityLabel_2.Enable='off';
                app.Density_2.Enable='off';
                app.kgm3Label_2.Enable='off';
            end
        end

        % Value changed function: Viscosity_2
        function Viscosity_2ValueChanged(app, event)
            value = app.Viscosity_2.Value;
            if value<2000
                app.FlowLabel_2.Text="Laminar flow";
                if app.DropDown_2.Value=="Reynold's number"
                app.CheckBoxL.Enable='on'; [app.Ks_1.Enable,app.Ks_2.Enable,app.Ks_3.Enable,app.mmLabel_1.Enable,app.mmLabel_2.Enable,app.mmLabel_3.Enable,app.EquivalentsandroughnessLabel_1.Enable,app.EquivalentsandroughnessLabel_2.Enable,app.EquivalentsandroughnessLabel_3.Enable]=deal('off');
                end
                elseif 2000<=value && value<3000
               app.FlowLabel_2.Text="Transitional flow";
               app.CheckBoxL.Enable='off';
               app.CheckBoxL.Value=false;[app.Ks_1.Enable,app.Ks_2.Enable,app.Ks_3.Enable,app.mmLabel_1.Enable,app.mmLabel_2.Enable,app.mmLabel_3.Enable,app.EquivalentsandroughnessLabel_1.Enable,app.EquivalentsandroughnessLabel_2.Enable,app.EquivalentsandroughnessLabel_3.Enable]=deal('on');
            elseif value>=3000
                app.FlowLabel_2.Text="Turbulent flow";
                app.CheckBoxL.Enable='off';
               app.CheckBoxL.Value=false;[app.Ks_1.Enable,app.Ks_2.Enable,app.Ks_3.Enable,app.mmLabel_1.Enable,app.mmLabel_2.Enable,app.mmLabel_3.Enable,app.EquivalentsandroughnessLabel_1.Enable,app.EquivalentsandroughnessLabel_2.Enable,app.EquivalentsandroughnessLabel_3.Enable]=deal('on');
            end
            if value==0
                app.FlowLabel_2.Text="";
            end
        end

        % Value changed function: CircularProfileCheckBox_1
        function CircularProfileCheckBox_1ValueChanged(app, event)
            value = app.CircularProfileCheckBox_1.Value;
            if value
                app.AreaLabel_1.Enable='off';
                app.Area_1.Enable='off';
                app.mSqLabel_1.Enable='off';
                app.WettedperimeterLabel_1.Enable='off';
                app.Perimeter_1.Enable='off';
                app.mLabel_4_1.Enable='off';
                app.Diameter_1.Enable='on';
                app.mLabel_3_1.Enable='on';
            else
               app.AreaLabel_1.Enable='on';
                app.Area_1.Enable='on';
                app.mSqLabel_1.Enable='on';
                app.WettedperimeterLabel_1.Enable='on';
                app.Perimeter_1.Enable='on';
                app.mLabel_4_1.Enable='on';
                app.Diameter_1.Enable='off';
                app.mLabel_3_1.Enable='off';
            end

        end

        % Value changed function: FullyturbulentflowCheckBox_1
        function FullyturbulentflowCheckBox_1ValueChanged(app, event)
            value = app.FullyturbulentflowCheckBox_1.Value;
            if value
                if app.DropDownFlow_1.Value=="Mass flow rate"
                    app.DensityLabel_1.Enable='on';
                    app.Density_1.Enable='on';
                    app.kgm3Label_1.Enable='on';
                else
                    app.DensityLabel_1.Enable='off';
                    app.Density_1.Enable='off';
                    app.kgm3Label_1.Enable='off';
                end
                app.DropDown_1.Enable='off';
                app.ViscosityLabel_1.Enable='off';
                app.Viscosity_1.Enable='off';
            else
                if app.DropDown_1.Value=="Dynamic viscosity"
                    app.DensityLabel_1.Enable='on';
                    app.Density_1.Enable='on';
                    app.kgm3Label_1.Enable='on';
                end
                app.DropDown_1.Enable='on';
                app.ViscosityLabel_1.Enable='on';
                app.Viscosity_1.Enable='on';
            end
            
        end

        % Value changed function: DropDown_1
        function DropDown_1ValueChanged(app, event)
            value = app.DropDown_1.Value;
            if value=="Kinematic viscosity"
                app.CheckBoxL.Enable='off';
                app.CheckBoxL.Value=false;[app.Ks_1.Enable,app.Ks_2.Enable,app.Ks_3.Enable,app.mmLabel_1.Enable,app.mmLabel_2.Enable,app.mmLabel_3.Enable,app.EquivalentsandroughnessLabel_1.Enable,app.EquivalentsandroughnessLabel_2.Enable,app.EquivalentsandroughnessLabel_3.Enable]=deal('on');
                app.ViscosityLabel_1.Text="m^2/s";
                app.ViscosityLabel_1.Visible='on';
                app.FlowLabel_1.Enable='off';
                app.FlowLabel_1.Visible='off';
                if app.DropDownFlow_1.Value~="Mass flow rate"
                app.DensityLabel_1.Enable='off';
                app.Density_1.Enable='off';
                app.kgm3Label_1.Enable='off';
                end
            else
                if value=="Dynamic viscosity"
                    app.CheckBoxL.Enable='off';
                    app.CheckBoxL.Value=false;[app.Ks_1.Enable,app.Ks_2.Enable,app.Ks_3.Enable,app.mmLabel_1.Enable,app.mmLabel_2.Enable,app.mmLabel_3.Enable,app.EquivalentsandroughnessLabel_1.Enable,app.EquivalentsandroughnessLabel_2.Enable,app.EquivalentsandroughnessLabel_3.Enable]=deal('on');
                    app.ViscosityLabel_1.Text="Pa.s";
                    app.ViscosityLabel_1.Visible='on';
                    app.FlowLabel_1.Enable='off';
                    app.FlowLabel_1.Visible='off';
                else
                    app.ViscosityLabel_1.Visible='off';
                    app.FlowLabel_1.Enable='on';
                    app.FlowLabel_1.Visible='on';
                app.DensityLabel_1.Enable='on';
                app.Density_1.Enable='on';
                app.kgm3Label_1.Enable='on';
                end
                if value=="Reynold's number"
                    if app.Viscosity_1<2000
                    app.CheckBoxL.Enable='on'; [app.Ks_1.Enable,app.Ks_2.Enable,app.Ks_3.Enable,app.mmLabel_1.Enable,app.mmLabel_2.Enable,app.mmLabel_3.Enable,app.EquivalentsandroughnessLabel_1.Enable,app.EquivalentsandroughnessLabel_2.Enable,app.EquivalentsandroughnessLabel_3.Enable]=deal('off');
                    end
                app.ViscosityLabel_1.Visible='off';
                app.FlowLabel_1.Enable='on';
                app.FlowLabel_1.Visible='on';
                if app.DropDownFlow_1.Value~="Mass flow rate"
                app.DensityLabel_1.Enable='off';
                app.Density_1.Enable='off';
                app.kgm3Label_1.Enable='off';
                end
                end
            end
            if value=="Dynamic viscosity"
                app.DensityLabel_1.Enable='on';
                app.Density_1.Enable='on';
                app.kgm3Label_1.Enable='on';
            end


        end

        % Value changed function: Viscosity_1
        function Viscosity_1ValueChanged(app, event)
            value = app.Viscosity_1.Value;
            if value<2000
                app.FlowLabel_1.Text="Laminar flow";
                if app.DropDown_1.Value=="Reynold's number"
                app.CheckBoxL.Enable='on'; [app.Ks_1.Enable,app.Ks_2.Enable,app.Ks_3.Enable,app.mmLabel_1.Enable,app.mmLabel_2.Enable,app.mmLabel_3.Enable,app.EquivalentsandroughnessLabel_1.Enable,app.EquivalentsandroughnessLabel_2.Enable,app.EquivalentsandroughnessLabel_3.Enable]=deal('off');
                end
            elseif 2000<=value && value<3000
               app.FlowLabel_1.Text="Transitional flow";
               app.CheckBoxL.Enable='off';
               app.CheckBoxL.Value=false;[app.Ks_1.Enable,app.Ks_2.Enable,app.Ks_3.Enable,app.mmLabel_1.Enable,app.mmLabel_2.Enable,app.mmLabel_3.Enable,app.EquivalentsandroughnessLabel_1.Enable,app.EquivalentsandroughnessLabel_2.Enable,app.EquivalentsandroughnessLabel_3.Enable]=deal('on');
            elseif value>=3000
                app.CheckBoxL.Enable='off';
               app.CheckBoxL.Value=false;[app.Ks_1.Enable,app.Ks_2.Enable,app.Ks_3.Enable,app.mmLabel_1.Enable,app.mmLabel_2.Enable,app.mmLabel_3.Enable,app.EquivalentsandroughnessLabel_1.Enable,app.EquivalentsandroughnessLabel_2.Enable,app.EquivalentsandroughnessLabel_3.Enable]=deal('on');
                app.FlowLabel_1.Text="Turbulent flow";
            end
            if value==0
                app.FlowLabel_2.Text="";
            end

        end

        % Value changed function: Area_1
        function Area_1ValueChanged(app, event)
            area = app.Area_1.Value;
            perimeter=app.Perimeter_1.Value;
            if area~=0 && perimeter~=0
            app.Diameter_1.Value=4*(area)/(perimeter);
            end


        end

        % Value changed function: Perimeter_1
        function Perimeter_1ValueChanged(app, event)
            area = app.Area_1.Value;
            perimeter=app.Perimeter_1.Value;
            if area~=0 && perimeter~=0
            app.Diameter_1.Value=4*(area)/(perimeter);
            end


        end

        % Value changed function: DropDownFlow_1
        function DropDownFlow_1ValueChanged(app, event)
            value = app.DropDownFlow_1.Value;
            if value == "Volume flow rate"
                app.unitLabel_1.Text="m^3/s" ;
                if app.FullyturbulentflowCheckBox_1.Value && app.DropDown_1.Value~="Dynamic viscosity"
                    app.Density_1.Enable='off';
                    app.DensityLabel_1.Enable='off';
                    app.kgm3Label_1.Enable='off';
                end
            end
            if value == "Mass flow rate"
                app.unitLabel_1.Text="kg/s";
                app.Density_1.Enable='on';
                app.DensityLabel_1.Enable='on';
                app.kgm3Label_1.Enable='on';
            else
                %                 if app.DropDownKsorRe_1.Value=="Reynold's number"
                %                     app.Density_1.Enable='off';
                %                     app.DensityLabel_1.Enable='off';
                %                     app.kgm3Label_1.Enable='off';
                %                 end
            end
            if value == "Flow velocity"
                app.unitLabel_1.Text="m/s";
                if app.FullyturbulentflowCheckBox_1.Value && app.DropDown_1.Value~="Dynamic viscosity"
                    app.Density_1.Enable='off';
                    app.DensityLabel_1.Enable='off';
                    app.kgm3Label_1.Enable='off';
                end
            end


        end

        % Value changed function: ReynoldsnumberCheckBox_3
        function ReynoldsnumberCheckBox_3ValueChanged(app, event)
            value = app.ReynoldsnumberCheckBox_3.Value;
            if value
                if app.DropDownFlow_3.Value~="Mass flow rate"
                    app.Density_3.Enable='off';
                    app.DensityLabel_3.Enable='off';
                    app.kgm3Label_3.Enable='off';
                end
                app.DropDown_3.Enable='off';
                app.Viscosity_3.Enable='off';
                app.ViscosityLabel_3.Enable='off';
                app.Re_3.Enable='on';
                app.FlowLabel_3.Enable='on';
            else
                if app.DropDown_3.Value=="Dynamic viscosity"
                    app.Density_3.Enable='on';
                    app.DensityLabel_3.Enable='on';
                    app.kgm3Label_3.Enable='on';
                end
                app.Re_3.Enable='off';
                app.FlowLabel_3.Enable='off';
                app.DropDown_3.Enable='off';
                app.Viscosity_3.Enable='off';
                app.ViscosityLabel_3.Enable='off';
                app.DropDown_3.Enable='on';
                app.Viscosity_3.Enable='on';
                app.ViscosityLabel_3.Enable='on';
            end
            if app.DropDownFlow_3=="Mass flow rate"
                app.Density_3.Enable='on';
                app.DensityLabel_3.Enable='on';
                app.kgm3Label_3.Enable='on';
            end
        end

        % Button pushed function: Solve_1
        function Solve_1ButtonPushed(app, event)
            x=[app.PipeLength_1.Enable,app.VorQorM_1.Enable,app.Diameter_1.Enable,app.Area_1.Enable,app.Perimeter_1.Enable,app.Ks_1.Enable,app.Viscosity_1.Enable,app.Density_1.Enable];
            y=[app.PipeLength_1.Value,app.VorQorM_1.Value,app.Diameter_1.Value,app.Area_1.Value,app.Perimeter_1.Value,app.Ks_1.Value,app.Viscosity_1.Value,app.Density_1.Value];
            for i=1:length(x)
                if x(i)=='on' && y(i)==0
                    f = errordlg('Missing Variables. Make sure to input required data.','Error');
                    return
                end
                if y(i)<0
                    f = errordlg('Invalid input. Enter positive values only.','Error');
                    return
                end
            end
            L=app.PipeLength_1.Value;
            y=app.VorQorM_1.Value;
            x=app.DropDownFlow_1.Value;
            density=app.Density_1.Value;
            FTurb=app.FullyturbulentflowCheckBox_1.Value;
            d=app.Diameter_1.Value;
            Ks=app.Ks_1.Value/1000;
            Re=0;
            switch x
                case "Volume flow rate"
                    Q=y;
                    v=Q/(0.25*pi*d^2);
                case "Mass flow rate"
                    Q=y/density;
                    v=Q/(0.25*pi*d^2);
                case "Flow velocity"
                    v=y;
                    Q=v*(0.25*pi*d^2);
            end
            if app.DropDown_1.Value=="Reynold's number"
                Re=app.Viscosity_1.Value;
            else
                vis=app.Viscosity_1.Value;
            end
            if FTurb
                if app.CheckBoxL.Value
                    f=64/Re;
                else
                f=(0.5/log10(Ks/(3.7*d)))^2;
                end
                Hl=f*(L/d)*(v^2)/(2*9.81);
                app.Result_1.Value=Hl;
                return
            end
            if Re>0
                if app.CheckBoxL.Value
                    f=64/Re;
                else
                f=(0.5/log10(Ks/(3.7*d)+(5.74/(Re^0.9))))^2;
                end
                Hl=f*(L/d)*(v^2)/(2*9.81);
                app.Result_1.Value=Hl;
                return
            end
            if app.DropDown_1.Value=="Kinematic viscosity"
                Re=v*d/vis;
            else
                Re=density*v*d/vis;
            end
            if app.CheckBoxL.Value
                    f=64/Re;
                else
            f=(0.5/log10(Ks/(3.7*d)+(5.74/(Re^0.9))))^2;
            end
            Hl=f*(L/d)*(v^2)/(2*9.81);
            app.Result_1.Value=Hl;
            return
            
            
        end

        % Button pushed function: Solve_3
        function Solve_3ButtonPushed(app, event)
            x=[app.PipeLength_3.Enable,app.HeadLoss_3.Enable,app.VorQorM_3.Enable,app.Ks_3.Enable,app.Re_3.Enable,app.Density_3.Enable,app.Viscosity_3.Enable];
            y=[app.PipeLength_3.Value,app.HeadLoss_3.Value,app.VorQorM_3.Value,app.Ks_3.Value,app.Re_3.Value,app.Density_3.Value,app.Viscosity_3.Value];
            for i=1:length(x)
                if x(i)=='on' && y(i)==0
                    f = errordlg('Missing Variables. Make sure to input required data.','Error');
                    return
                end
                if y(i)<0
                    f = errordlg('Invalid input. Enter positive values only.','Error');
                    return
                end
            end
            L=app.PipeLength_3.Value;
            Hl=app.HeadLoss_3.Value;
            x=app.DropDownFlow_3.Value;
            Ks=app.Ks_3.Value/1000;
            Re=app.Re_3.Value;
            density=app.Density_3.Value;
            vis=app.Viscosity_3.Value;
            y=app.VorQorM_3.Value;
            FTurb=app.FullyturbulentflowCheckBox_3.Value;
            syms d
            switch x
                case "Volume flow rate"
                    Q=y;
                    v=Q/(0.25*pi*d^2);
                case "Mass flow rate"
                    Q=y/density;
                    v=Q/(0.25*pi*d^2);
                case "Flow velocity"
                    v=y;
                    Q=v*(0.25*pi*d^2);
            end
            if FTurb
                if app.CheckBoxL.Value
                    f=64/Re;
                else
                f=(0.5/log10(Ks/(3.7*d)))^2;
                end
                eqn=Hl==f*(L/d)*(v^2)/(2*9.81);
                S=vpasolve(eqn,d,[0,inf]);
                app.Result_3.Value=double(S);
                d=double(S);
                
                return
            end
            if Re>0
                if app.CheckBoxL.Value
                    f=64/Re;
                else
                f=(0.5/log10(Ks/(3.7*d)+(5.74/(Re^0.9))))^2;
                end
                eqn=Hl==f*(L/d)*(v^2)/(2*9.81);
                S=vpasolve(eqn,d,[0,inf]);
                app.Result_3.Value=double(S);
                d=double(S);

                return
            end
            if app.DropDown_3.Value=="Kinematic viscosity"
                Re=v*d/vis;
            else
                Re=density*v*d/vis;
            end
            if app.CheckBoxL.Value
                    f=64/Re;
                else
            f=(0.5/log10(Ks/(3.7*d)+(5.74/(Re^0.9))))^2;
            end
            eqn=Hl==f*(L/d)*(v^2)/(2*9.81);
            S=vpasolve(eqn,d,[0,inf]);
            S=double(S);
            app.Result_3.Value=S;
            d=S;

            return
            
        end

        % Button pushed function: Solve_2
        function Solve_2ButtonPushed(app, event)
            x=[app.PipeLength_2.Enable,app.HeadLoss_2.Enable,app.Diameter_2.Enable,app.Area_2.Enable,app.Perimeter_2.Enable,app.Ks_2.Enable,app.Viscosity_2.Enable,app.Density_2.Enable];
            y=[app.PipeLength_2.Value,app.HeadLoss_2.Value,app.Diameter_2.Value,app.Area_2.Value,app.Perimeter_2.Value,app.Ks_2.Value,app.Viscosity_2.Value,app.Density_2.Value];
            for i=1:length(x)
                if x(i)=='on' && y(i)==0
                    f = errordlg('Missing Variables. Make sure to input required data.','Error');
                    return
                end
                if y(i)<0
                    f = errordlg('Invalid input. Enter positive values only.','Error');
                    return
                end
            end
            L=app.PipeLength_2.Value;
            Hl=app.HeadLoss_2.Value;
            d=app.Diameter_2.Value;
            Ks=app.Ks_2.Value/1000;
            Re=0;
            if app.DropDown_2.Value=="Reynold's number"
                Re=app.Viscosity_2.Value;
            else
                vis=app.Viscosity_2.Value;
            end
            density=app.Density_2.Value;
            FTurb=app.FullyturbulentflowCheckBox_2.Value;
            syms Q v
            if FTurb
                if app.CheckBoxL.Value
                    f=64/Re;
                else
                f=(0.5/log10(Ks/(3.7*d)))^2;
                end
                eqn=Hl==f*(L/d)*(v^2)/(2*9.81);
                S=vpasolve(eqn,v,[0,inf]);
                Q=S*(0.25*pi*d^2);
                app.Result_2.Value=double(Q);

                return
            end
            if Re>0
                if app.CheckBoxL.Value
                    f=64/Re;
                else
                f=(0.5/log10(Ks/(3.7*d)+(5.74/(double(Re)^0.9))))^2;
                end
                eqn=Hl==f*(L/d)*(v^2)/(2*9.81);
                S=vpasolve(eqn,v,[0,inf]);
                Q=S*(0.25*pi*d^2);
                app.Result_2.Value=double(Q);

                return
            end
            if app.DropDown_2.Value=="Kinematic viscosity"
                Re=v*d/vis;
            else
                Re=density*v*d/vis;
            end
            if app.CheckBoxL.Value
                    f=64/Re;
                else
            f=(0.5/log10(Ks/(3.7*d)+(5.74/(Re^0.9))))^2;
            end
            eqn=Hl==f*(L/d)*(v^2)/(2*9.81);
            S=vpasolve(eqn,v,[0,inf]);
            S=double(S);
            Q=S*(0.25*pi*d^2);
            app.Result_2.Value=double(Q);

            return
        end

        % Value changed function: Components
        function ComponentsValueChanged(app, event)
            value = app.Components.Value;
            if value=="Component 1"
                app.Components.Items = {'Component 3','Component 1', 'Component 2'};
            end
        end

        % Callback function
        function Button4Pushed(app, event)
            app.Components.Value='Component 2';
        end

        % Selection changed function: TypeList
        function TypeListSelectionChanged(app, event)
            selectedNodes = app.TypeList.SelectedNodes;
            switch selectedNodes
                case app.PipeenteranceNode
                    [app.img_PipeEnterance.Visible,app.img_Contraction.Visible,app.img_Expansion.Visible,app.img_SmoothBend.Visible,app.img_General.Visible]=deal('off');
                    app.img_PipeEnterance.Visible='on';
                case app.ContractionNode
                    [app.img_PipeEnterance.Visible,app.img_Contraction.Visible,app.img_Expansion.Visible,app.img_SmoothBend.Visible,app.img_General.Visible]=deal('off');
                    app.img_Contraction.Visible='on';
                case app.ExpansionNode
                    [app.img_PipeEnterance.Visible,app.img_Contraction.Visible,app.img_Expansion.Visible,app.img_SmoothBend.Visible,app.img_General.Visible]=deal('off');
                    app.img_Expansion.Visible='on';
                case app.degreesmoothbendNode
                    [app.img_PipeEnterance.Visible,app.img_Contraction.Visible,app.img_Expansion.Visible,app.img_SmoothBend.Visible,app.img_General.Visible]=deal('off');
                    app.img_SmoothBend.Visible='on';
                otherwise
                    [app.img_PipeEnterance.Visible,app.img_Contraction.Visible,app.img_Expansion.Visible,app.img_SmoothBend.Visible,app.img_General.Visible]=deal('off');
                    app.img_General.Visible='on';
            end
            switch selectedNodes
                case app.PipeenteranceNode
                    [app.r.Visible,app.d.Visible,app.rd.Visible,app.CheckBox.Visible,app.rEditFieldLabel.Visible,app.dEditFieldLabel.Visible,app.rdEditFieldLabel.Visible]=deal('on');
                    [app.D1D2.Visible,app.ThetaLabel.Visible,app.D1.Visible,app.D2.Visible,app.Theta.Visible,app.CheckBox_2.Visible,app.D1EditFieldLabel.Visible,app.D2EditFieldLabel.Visible,app.D1D2EditFieldLabel.Visible]=deal('off');
                    app.ComponentName.Value="Pipe enterance";
                case app.ContractionNode
                    app.Theta.Items{1} = '60°';
                    [app.r.Visible,app.d.Visible,app.rd.Visible,app.CheckBox.Visible,app.rEditFieldLabel.Visible,app.dEditFieldLabel.Visible,app.rdEditFieldLabel.Visible]=deal('off');
                    [app.D1D2.Visible,app.ThetaLabel.Visible,app.D1.Visible,app.D2.Visible,app.Theta.Visible,app.CheckBox_2.Visible,app.D1EditFieldLabel.Visible,app.D2EditFieldLabel.Visible,app.D1D2EditFieldLabel.Visible]=deal('on');
                    app.ComponentName.Value="Contraction";
                case app.ExpansionNode
                    app.Theta.Items{1} = '20°';
                    [app.r.Visible,app.d.Visible,app.rd.Visible,app.CheckBox.Visible,app.rEditFieldLabel.Visible,app.dEditFieldLabel.Visible,app.rdEditFieldLabel.Visible]=deal('off');
                    [app.D1D2.Visible,app.ThetaLabel.Visible,app.D1.Visible,app.D2.Visible,app.Theta.Visible,app.CheckBox_2.Visible,app.D1EditFieldLabel.Visible,app.D2EditFieldLabel.Visible,app.D1D2EditFieldLabel.Visible]=deal('on');
                    app.ComponentName.Value="Expansion";
                case app.degreemiterbendNode
                    [app.r.Visible,app.d.Visible,app.rd.Visible,app.CheckBox.Visible,app.rEditFieldLabel.Visible,app.dEditFieldLabel.Visible,app.rdEditFieldLabel.Visible]=deal('off');
                    [app.D1D2.Visible,app.ThetaLabel.Visible,app.D1.Visible,app.D2.Visible,app.Theta.Visible,app.CheckBox_2.Visible,app.D1EditFieldLabel.Visible,app.D2EditFieldLabel.Visible,app.D1D2EditFieldLabel.Visible]=deal('off');
                    app.ComponentName.Value="90-degree miter bend";
                    kh=1.1;
                case app.degreesmoothbendNode
                    [app.r.Visible,app.d.Visible,app.rd.Visible,app.CheckBox.Visible,app.rEditFieldLabel.Visible,app.dEditFieldLabel.Visible,app.rdEditFieldLabel.Visible]=deal('on');
                    [app.D1D2.Visible,app.ThetaLabel.Visible,app.D1.Visible,app.D2.Visible,app.Theta.Visible,app.CheckBox_2.Visible,app.D1EditFieldLabel.Visible,app.D2EditFieldLabel.Visible,app.D1D2EditFieldLabel.Visible]=deal('off');
                    app.ComponentName.Value="90-degree smooth bend";
                case app.ThreadedpipefittingsNode 
                    [app.r.Visible,app.d.Visible,app.rd.Visible,app.CheckBox.Visible,app.rEditFieldLabel.Visible,app.dEditFieldLabel.Visible,app.rdEditFieldLabel.Visible]=deal('off');
                    [app.D1D2.Visible,app.ThetaLabel.Visible,app.D1.Visible,app.D2.Visible,app.Theta.Visible,app.CheckBox_2.Visible,app.D1EditFieldLabel.Visible,app.D2EditFieldLabel.Visible,app.D1D2EditFieldLabel.Visible]=deal('off');
                case app.TeeNode
                    [app.r.Visible,app.d.Visible,app.rd.Visible,app.CheckBox.Visible,app.rEditFieldLabel.Visible,app.dEditFieldLabel.Visible,app.rdEditFieldLabel.Visible]=deal('off');
                    [app.D1D2.Visible,app.ThetaLabel.Visible,app.D1.Visible,app.D2.Visible,app.Theta.Visible,app.CheckBox_2.Visible,app.D1EditFieldLabel.Visible,app.D2EditFieldLabel.Visible,app.D1D2EditFieldLabel.Visible]=deal('off');
                otherwise
                    [app.r.Visible,app.d.Visible,app.rd.Visible,app.CheckBox.Visible,app.rEditFieldLabel.Visible,app.dEditFieldLabel.Visible,app.rdEditFieldLabel.Visible]=deal('off');
                    [app.D1D2.Visible,app.ThetaLabel.Visible,app.D1.Visible,app.D2.Visible,app.Theta.Visible,app.CheckBox_2.Visible,app.D1EditFieldLabel.Visible,app.D2EditFieldLabel.Visible,app.D1D2EditFieldLabel.Visible]=deal('off');
                    
            end
            if selectedNodes==app.ThreadedpipefittingsNode  || selectedNodes==app.TeeNode
                app.AddButton.Enable='off';
            else
                app.AddButton.Enable='on';
            end
            switch selectedNodes
                case app.GlobevalvewideopenNode
                    app.ComponentName.Value="Globe valve (wide open)";
                case app.AnglevalvewideopenNode
                    app.ComponentName.Value="Angle valve (wide open)";
                case app.GatevalvewideopenNode
                    app.ComponentName.Value="Gate valve (wide open)";
                case app.GatevalvehalfopenNode
                    app.ComponentName.Value="Gate valve (half open)";
                case app.ReturnbendNode
                    app.ComponentName.Value="Return bend";
                case app.degreeelbowNode
                    app.ComponentName.Value="90-degree elbow";
                case app.degreeelbowNode_2
                    app.ComponentName.Value="45-degree elbow";
                case app.StraightthroughflowNode
                    app.ComponentName.Value="Straight-through flow";
                case app.SideoutletflowNode
                    app.ComponentName.Value="Side-outlet flow";
            end
            switch selectedNodes
                case app.GlobevalvewideopenNode
                    app.Kh.Value=10;
                case app.AnglevalvewideopenNode
                    app.Kh.Value=5;
                case app.GatevalvewideopenNode
                    app.Kh.Value=0.2;
                case app.GatevalvehalfopenNode
                    app.Kh.Value=5.6;
                case app.ReturnbendNode
                    app.Kh.Value=2.2;
                case app.degreeelbowNode
                    app.Kh.Value=0.4;
                case app.degreeelbowNode_2
                    app.Kh.Value=1.8;
                case app.StraightthroughflowNode
                    app.Kh.Value=0.9;
                case app.SideoutletflowNode
                    app.Kh.Value=0.4;
                        
            end
            
        end

        % Value changed function: d
        function dValueChanged(app, event)
            app.rd.Value=app.r.Value/app.d.Value;
        end

        % Value changed function: r
        function rValueChanged(app, event)
        app.rd.Value=app.r.Value/app.d.Value;
        end

        % Value changed function: D1
        function D1ValueChanged(app, event)
          app.D1D2.Value=app.D1.Value/app.D2.Value;
        end

        % Value changed function: D2
        function D2ValueChanged(app, event)
          app.D1D2.Value=app.D1.Value/app.D2.Value;
        end

        % Value changed function: CheckBox_2
        function CheckBox_2ValueChanged(app, event)
            value = app.CheckBox_2.Value;
            if value
                [app.D1D2.Enable,app.D1D2EditFieldLabel.Enable,app.D1D2.Enable]=deal('on');
                [app.D1.Enable, app.D2.Enable, app.D1EditFieldLabel.Enable, app.D2EditFieldLabel.Enable]=deal('off');
            else
                app.D1D2.Enable='off';
                [app.D1.Enable, app.D2.Enable, app.D1EditFieldLabel.Enable, app.D2EditFieldLabel.Enable]=deal('on');
            end
        end

        % Value changed function: CheckBox
        function CheckBoxValueChanged(app, event)
            value = app.CheckBox.Value;
            if value
                [app.rd.Enable,app.rdEditFieldLabel.Enable,app.rd.Enable]=deal('on');
                [app.r.Enable, app.d.Enable, app.rEditFieldLabel.Enable, app.dEditFieldLabel.Enable]=deal('off');
            else
                [app.rd.Editable,app.r.Enable, app.d.Enable, app.rEditFieldLabel.Enable, app.dEditFieldLabel.Enable]=deal('on');
                app.rd.Enable='off';
            end
        end

        % Value changed function: CheckBox_3
        function CheckBox_3ValueChanged(app, event)
            value = app.CheckBox_3.Value;
            if value 
                app.K.Enable='on';
                app.KLabel_2.Enable='on';
                app.TypeList.Enable='off';
                app.AddButton.Enable='on';
                [app.r.Enable,app.d.Enable,app.rd.Enable,app.CheckBox.Enable,app.rEditFieldLabel.Enable,app.dEditFieldLabel.Enable,app.rdEditFieldLabel.Enable]=deal('off');
                    [app.D1D2.Enable,app.ThetaLabel.Enable,app.D1.Enable,app.D2.Enable,app.Theta.Enable,app.CheckBox_2.Enable,app.D1EditFieldLabel.Enable,app.D2EditFieldLabel.Enable,app.D1D2EditFieldLabel.Enable]=deal('off');
            else
                [app.r.Enable,app.d.Enable,app.rd.Enable,app.CheckBox.Enable,app.rEditFieldLabel.Enable,app.dEditFieldLabel.Enable,app.rdEditFieldLabel.Enable]=deal('on');
                    [app.D1D2.Enable,app.ThetaLabel.Enable,app.D1.Enable,app.D2.Enable,app.Theta.Enable,app.CheckBox_2.Enable,app.D1EditFieldLabel.Enable,app.D2EditFieldLabel.Enable,app.D1D2EditFieldLabel.Enable]=deal('on');
                    if app.CheckBox.Value
                [app.rd.Enable,app.rdEditFieldLabel.Enable,app.rd.Enable]=deal('on');
                [app.r.Enable, app.d.Enable, app.rEditFieldLabel.Enable, app.dEditFieldLabel.Enable]=deal('off');
            else
                [app.rd.Editable,app.r.Enable, app.d.Enable, app.rEditFieldLabel.Enable, app.dEditFieldLabel.Enable]=deal('on');
                app.rd.Enable='off';
                    end
                    if app.CheckBox_2.Value
                [app.D1D2.Enable,app.D1D2EditFieldLabel.Enable,app.D1D2.Enable]=deal('on');
                [app.D1.Enable, app.D2.Enable, app.D1EditFieldLabel.Enable, app.D2EditFieldLabel.Enable]=deal('off');
            else
                app.D1D2.Enable='off';
                [app.D1.Enable, app.D2.Enable, app.D1EditFieldLabel.Enable, app.D2EditFieldLabel.Enable]=deal('on');
                    end
                app.TypeList.Enable='on';
                app.KLabel_2.Enable='off';
                app.K.Enable='off';
                if app.TypeList.SelectedNodes==app.ThreadedpipefittingsNode || app.TypeList.SelectedNodes==app.TeeNode
                    app.AddButton.Enable='off';
                else
                    app.AddButton.Enable='on';
                end
            end
        end

        % Value changed function: Kh
        function KhValueChanged(app, event)

            
        end

        % Button pushed function: AddButton
        function AddButtonPushed(app, event)
            selectedNodes = app.TypeList.SelectedNodes;
            k=length(app.Components.Items)+1;
            if app.CheckBox_3.Value
                app.KList.Items{k}=num2str(app.K.Value);
            else
            switch selectedNodes
                case app.PipeenteranceNode
                    if app.rd.Value==inf || app.rd.Value<0
                        f = errordlg('Invalid input.','Error');
                        return
                    end
                    if app.rd.Value<=0.1
                        app.Kh.Value=0.5-(0.38/0.1)*(app.rd.Value);
                    elseif app.rd.Value<=0.2
                        app.Kh.Value=0.12-(0.09/0.1)*(app.rd.Value-0.1);
                    else
                        app.Kh.Value=0.03;
                    end
                case app.ContractionNode
                    if app.D1D2.Value==0 || app.D1D2.Value<0
                        f = errordlg('Invalid input.','Error');
                        return
                    end
                    p=1/app.D1D2.Value;
                    switch app.Theta.Value
                        case '60°'
                    if p<=0.4
                        app.Kh.Value=0.08;
                    elseif p<=0.6
                        app.Kh.Value=0.08-(0.01/0.2)*(p-0.4);
                    elseif p<=0.8
                        app.Kh.Value=0.07-(0.01/0.2)*(p-0.6);
                    else
                        app.Kh.Value=0.06;
                    end
                        case '180°'
                    if p<=0.2
                        app.Kh.Value=0.5;
                    elseif p<=0.4
                        app.Kh.Value=0.49-(0.07/0.2)*(p-0.2);
                    elseif p<=0.6
                        app.Kh.Value=0.42-(0.15/0.2)*(p-0.4);
                    elseif p<=0.8
                        app.Kh.Value=0.27-(0.07/0.2)*(p-0.6);
                    elseif p<=0.9
                        app.Kh.Value=0.2-(p-0.8);
                    else
                        app.Kh.Value=0.1-(p-0.9);
                    end
                    end
                case app.ExpansionNode
                    if app.D1D2.Value==inf || app.D1D2.Value<0
                        f = errordlg('Invalid input.','Error');
                        return
                    end
                    switch app.Theta.Value
                        case '20°'
                    if app.D1D2.Value<=0.2
                        app.Kh.Value=0.3;
                    elseif app.D1D2.Value<=0.4
                        app.Kh.Value=0.3-(0.05/0.2)*(app.D1D2.Value-0.2);
                    elseif app.D1D2.Value<=0.6
                        app.Kh.Value=0.25-(0.1/0.2)*(app.D1D2.Value-0.4);
                    elseif app.D1D2.Value<=0.8
                        app.Kh.Value=0.15-(0.05/0.2)*(app.D1D2.Value-0.6);
                    else
                        app.Kh.Value=0.1-0.5*(app.D1D2.Value-0.8);
                    end
                        case '180°'
                    if app.D1D2.Value<=0.2
                        app.Kh.Value=1-(0.13/0.2)*(app.D1D2.Value);
                    elseif app.D1D2.Value<=0.4
                        app.Kh.Value=0.87-(0.17/0.2)*(app.D1D2.Value-0.2);
                    elseif app.D1D2.Value<=0.6
                        app.Kh.Value=0.7-(0.29/0.2)*(app.D1D2.Value-0.4);
                    elseif app.D1D2.Value<=0.8
                        app.Kh.Value=0.41-(0.26/0.2)*(app.D1D2.Value-0.6);
                    else
                        app.Kh.Value=0.15-(0.15/0.2)*(app.D1D2.Value-0.8);
                    end
                    end
                case app.degreesmoothbendNode
                    if app.rd.Value==inf || app.rd.Value<0
                        f = errordlg('Invalid input.','Error');
                        return
                    end
                    if app.rd.Value<=1
                        app.Kh.Value=1.1-(0.75/1)*(app.rd.Value);
                    elseif app.rd.Value<=2
                        app.Kh.Value=0.35-(0.16/1)*(app.rd.Value-1);
                    elseif app.rd.Value<=3
                        app.Kh.Value=0.19-(0.03/1)*(app.rd.Value-2);
                    elseif app.rd.Value<=4
                        app.Kh.Value=0.16+(0.05/1)*(app.rd.Value-3);
                    elseif app.rd.Value<=6
                        app.Kh.Value=0.21+(0.07/2)*(app.rd.Value-4);
                    elseif app.rd.Value<=8
                        app.Kh.Value=0.28+(0.04/2)*(app.rd.Value-6);
                    else
                        app.Kh.Value=0.32+(0.04/2)*(app.rd.Value-8);
                    end
                    
                    case app.ExpansionNode
                    switch app.Theta.Value
                        case '20°'
                    if app.D1D2.Value<=0.2
                        app.Kh.Value=0.3;
                    elseif app.D1D2.Value<=0.4
                        app.Kh.Value=0.3-(0.05/0.2)*(app.D1D2.Value-0.2);
                    elseif app.D1D2.Value<=0.6
                        app.Kh.Value=0.25-(0.1/0.2)*(app.D1D2.Value-0.4);
                    elseif app.D1D2.Value<=0.8
                        app.Kh.Value=0.15-(0.05/0.2)*(app.D1D2.Value-0.6);
                    else
                        app.Kh.Value=0.1-0.5*(app.D1D2.Value-0.8);
                    end
                        case '180°'
                    if app.D1D2.Value<=0.2
                        app.Kh.Value=1-(0.13/0.2)*(app.D1D2.Value);
                    elseif app.D1D2.Value<=0.4
                        app.Kh.Value=0.87-(0.17/0.2)*(app.D1D2.Value-0.2);
                    elseif app.D1D2.Value<=0.6
                        app.Kh.Value=0.7-(0.29/0.2)*(app.D1D2.Value-0.4);
                    elseif app.D1D2.Value<=0.8
                        app.Kh.Value=0.41-(0.26/0.2)*(app.D1D2.Value-0.6);
                    else
                        app.Kh.Value=0.15-(0.15/0.2)*(app.D1D2.Value-0.8);
                    end
                    end
            end
            app.KList.Items{k}=num2str(app.Kh.Value);
            end
            app.Components.Items{k}=app.ComponentName.Value;
            app.Types.Items{k}=app.TypeList.SelectedNodes.Text;
            sum=0;
            for i=1:k
                sum=sum+str2double(app.KList.Items{i});
            end
            app.TotalK.Value=sum;
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            app.Components.Items={};
            app.Types.Items={};
            app.TotalK.Value=0;
            app.KList.Items={};
        end

        % Value changed function: Viscosity_3
        function Viscosity_3ValueChanged(app, event)

            
        end

        % Value changed function: CheckBoxL
        function CheckBoxLValueChanged(app, event)
            value = app.CheckBoxL.Value;
            if value
                [app.Ks_1.Enable,app.Ks_2.Enable,app.Ks_3.Enable,app.mmLabel_1.Enable,app.mmLabel_2.Enable,app.mmLabel_3.Enable,app.EquivalentsandroughnessLabel_1.Enable,app.EquivalentsandroughnessLabel_2.Enable,app.EquivalentsandroughnessLabel_3.Enable]=deal('off');
            else
                [app.Ks_1.Enable,app.Ks_2.Enable,app.Ks_3.Enable,app.mmLabel_1.Enable,app.mmLabel_2.Enable,app.mmLabel_3.Enable,app.EquivalentsandroughnessLabel_1.Enable,app.EquivalentsandroughnessLabel_2.Enable,app.EquivalentsandroughnessLabel_3.Enable]=deal('on');
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.PipeFlowUIFigure = uifigure('Visible', 'off');
            app.PipeFlowUIFigure.Name = 'Pipe Flow';
            app.PipelengthLabel_3 = uilabel(app.PipeFlowUIFigure);
            app.PipelengthLabel_3.Tag = 'Case 3';
            app.PipelengthLabel_3.Visible = 'off';
            app.PipelengthLabel_3.Text = 'Pipe length';
            app.HeadlossLabel_3 = uilabel(app.PipeFlowUIFigure);
            app.HeadlossLabel_3.Tag = 'Case 3';
            app.HeadlossLabel_3.Visible = 'off';
            app.HeadlossLabel_3.Text = 'Head loss';
            app.FullyturbulentflowCheckBox_3 = uicheckbox(app.PipeFlowUIFigure);
            app.FullyturbulentflowCheckBox_3.ValueChangedFcn = createCallbackFcn(app, @FullyturbulentflowCheckBox_3ValueChanged, true);
            app.FullyturbulentflowCheckBox_3.Tag = 'Case 3';
            app.FullyturbulentflowCheckBox_3.Visible = 'off';
            app.FullyturbulentflowCheckBox_3.Text = 'Fully turbulent flow';
            app.FullyturbulentflowCheckBox_3.Value = true;
            app.Re_3 = uieditfield(app.PipeFlowUIFigure, 'numeric');
            app.Re_3.ValueChangedFcn = createCallbackFcn(app, @Re_3ValueChanged, true);
            app.Re_3.Tag = 'Case 3';
            app.Re_3.Enable = 'off';
            app.Re_3.Visible = 'off';
            app.PipeLength_3 = uieditfield(app.PipeFlowUIFigure, 'numeric');
            app.PipeLength_3.Tag = 'Case 3';
            app.PipeLength_3.Visible = 'off';
            app.HeadLoss_3 = uieditfield(app.PipeFlowUIFigure, 'numeric');
            app.HeadLoss_3.Tag = 'Case 3';
            app.HeadLoss_3.Visible = 'off';
            app.DropDownFlow_3 = uidropdown(app.PipeFlowUIFigure);
            app.DropDownFlow_3.Items = {'Volume flow rate', 'Flow velocity', 'Mass flow rate'};
            app.DropDownFlow_3.ValueChangedFcn = createCallbackFcn(app, @DropDownFlow_3ValueChanged, true);
            app.DropDownFlow_3.Tag = 'Case 3';
            app.DropDownFlow_3.Visible = 'off';
            app.DropDownFlow_3.Value = 'Volume flow rate';
            app.mLabel_3 = uilabel(app.PipeFlowUIFigure);
            app.mLabel_3.Tag = 'Case 3';
            app.mLabel_3.Visible = 'off';
            app.mLabel_3.Text = 'm';
            app.mLabel_2_3 = uilabel(app.PipeFlowUIFigure);
            app.mLabel_2_3.Tag = 'Case 3';
            app.mLabel_2_3.Visible = 'off';
            app.mLabel_2_3.Text = 'm';
            app.VorQorM_3 = uieditfield(app.PipeFlowUIFigure, 'numeric');
            app.VorQorM_3.Tag = 'Case 3';
            app.VorQorM_3.Visible = 'off';
            app.unitLabel_3 = uilabel(app.PipeFlowUIFigure);
            app.unitLabel_3.Tag = 'Case 3';
            app.unitLabel_3.Visible = 'off';
            app.unitLabel_3.Text = 'm^3/s';
            app.mmLabel_3 = uilabel(app.PipeFlowUIFigure);
            app.mmLabel_3.Tag = 'Case 3';
            app.mmLabel_3.Visible = 'off';
            app.mmLabel_3.Text = 'mm';
            app.DensityLabel_3 = uilabel(app.PipeFlowUIFigure);
            app.DensityLabel_3.Tag = 'Case 3';
            app.DensityLabel_3.Enable = 'off';
            app.DensityLabel_3.Visible = 'off';
            app.DensityLabel_3.Text = 'Density';
            app.Density_3 = uieditfield(app.PipeFlowUIFigure, 'numeric');
            app.Density_3.Tag = 'Case 3';
            app.Density_3.Enable = 'off';
            app.Density_3.Visible = 'off';
            app.kgm3Label_3 = uilabel(app.PipeFlowUIFigure);
            app.kgm3Label_3.Tag = 'Case 3';
            app.kgm3Label_3.Enable = 'off';
            app.kgm3Label_3.Visible = 'off';
            app.kgm3Label_3.Text = 'kg/m^3';
            app.DropDown_3 = uidropdown(app.PipeFlowUIFigure);
            app.DropDown_3.Items = {'Dynamic viscosity', 'Kinematic viscosity'};
            app.DropDown_3.ValueChangedFcn = createCallbackFcn(app, @DropDown_3ValueChanged, true);
            app.DropDown_3.Tag = 'Case 3';
            app.DropDown_3.Enable = 'off';
            app.DropDown_3.Visible = 'off';
            app.DropDown_3.Value = 'Kinematic viscosity';
            app.Viscosity_3 = uieditfield(app.PipeFlowUIFigure, 'numeric');
            app.Viscosity_3.ValueChangedFcn = createCallbackFcn(app, @Viscosity_3ValueChanged, true);
            app.Viscosity_3.Tag = 'Case 3';
            app.Viscosity_3.Enable = 'off';
            app.Viscosity_3.Visible = 'off';
            app.ViscosityLabel_3 = uilabel(app.PipeFlowUIFigure);
            app.ViscosityLabel_3.Tag = 'Case 3';
            app.ViscosityLabel_3.Enable = 'off';
            app.ViscosityLabel_3.Visible = 'off';
            app.ViscosityLabel_3.Text = 'm^2/s';
            app.FlowLabel_3 = uilabel(app.PipeFlowUIFigure);
            app.FlowLabel_3.Tag = 'Case 3';
            app.FlowLabel_3.Enable = 'off';
            app.FlowLabel_3.Visible = 'off';
            app.FlowLabel_3.Text = '';
            app.FindHeadLossButton = uibutton(app.PipeFlowUIFigure, 'push');
            app.FindHeadLossButton.ButtonPushedFcn = createCallbackFcn(app, @FindHeadLossButtonPushed, true);
            app.FindHeadLossButton.Text = 'Find Head Loss';
            app.FindFlowRateButton = uibutton(app.PipeFlowUIFigure, 'push');
            app.FindFlowRateButton.ButtonPushedFcn = createCallbackFcn(app, @FindFlowRateButtonPushed, true);
            app.FindFlowRateButton.Text = 'Find Flow Rate';
            app.FindFlowDiameterButton = uibutton(app.PipeFlowUIFigure, 'push');
            app.FindFlowDiameterButton.ButtonPushedFcn = createCallbackFcn(app, @FindFlowDiameterButtonPushed, true);
            app.FindFlowDiameterButton.Text = 'Find Flow Diameter';
            app.PipelengthLabel_2 = uilabel(app.PipeFlowUIFigure);
            app.PipelengthLabel_2.Tag = 'Case 2';
            app.PipelengthLabel_2.Visible = 'off';
            app.PipelengthLabel_2.Text = 'Pipe length';
            app.HeadlossLabel_2 = uilabel(app.PipeFlowUIFigure);
            app.HeadlossLabel_2.Tag = 'Case 2';
            app.HeadlossLabel_2.Visible = 'off';
            app.HeadlossLabel_2.Text = 'Head loss';
            app.FullyturbulentflowCheckBox_2 = uicheckbox(app.PipeFlowUIFigure);
            app.FullyturbulentflowCheckBox_2.ValueChangedFcn = createCallbackFcn(app, @FullyturbulentflowCheckBox_2ValueChanged, true);
            app.FullyturbulentflowCheckBox_2.Tag = 'Case 2';
            app.FullyturbulentflowCheckBox_2.Visible = 'off';
            app.FullyturbulentflowCheckBox_2.Text = 'Fully turbulent flow';
            app.FullyturbulentflowCheckBox_2.Value = true;
            app.Ks_2 = uieditfield(app.PipeFlowUIFigure, 'numeric');
            app.Ks_2.Tag = 'Case 2';
            app.Ks_2.Visible = 'off';
            app.PipeLength_2 = uieditfield(app.PipeFlowUIFigure, 'numeric');
            app.PipeLength_2.Tag = 'Case 2';
            app.PipeLength_2.Visible = 'off';
            app.HeadLoss_2 = uieditfield(app.PipeFlowUIFigure, 'numeric');
            app.HeadLoss_2.Tag = 'Case 2';
            app.HeadLoss_2.Visible = 'off';
            app.mLabel_2 = uilabel(app.PipeFlowUIFigure);
            app.mLabel_2.Tag = 'Case 2';
            app.mLabel_2.Visible = 'off';
            app.mLabel_2.Text = 'm';
            app.mLabel_2_2 = uilabel(app.PipeFlowUIFigure);
            app.mLabel_2_2.Tag = 'Case 2';
            app.mLabel_2_2.Visible = 'off';
            app.mLabel_2_2.Text = 'm';
            app.mmLabel_2 = uilabel(app.PipeFlowUIFigure);
            app.mmLabel_2.Tag = 'Case 2';
            app.mmLabel_2.Visible = 'off';
            app.mmLabel_2.Text = 'mm';
            app.DensityLabel_2 = uilabel(app.PipeFlowUIFigure);
            app.DensityLabel_2.Tag = 'Case 2';
            app.DensityLabel_2.Enable = 'off';
            app.DensityLabel_2.Visible = 'off';
            app.DensityLabel_2.Text = 'Density';
            app.Density_2 = uieditfield(app.PipeFlowUIFigure, 'numeric');
            app.Density_2.Tag = 'Case 2';
            app.Density_2.Enable = 'off';
            app.Density_2.Visible = 'off';
            app.kgm3Label_2 = uilabel(app.PipeFlowUIFigure);
            app.kgm3Label_2.Tag = 'Case 2';
            app.kgm3Label_2.Enable = 'off';
            app.kgm3Label_2.Visible = 'off';
            app.kgm3Label_2.Text = 'kg/m^3';
            app.DropDown_2 = uidropdown(app.PipeFlowUIFigure);
            app.DropDown_2.Items = {'Reynold''s number', 'Dynamic viscosity', 'Kinematic viscosity'};
            app.DropDown_2.ValueChangedFcn = createCallbackFcn(app, @DropDown_2ValueChanged, true);
            app.DropDown_2.Tag = 'Case 2';
            app.DropDown_2.Enable = 'off';
            app.DropDown_2.Visible = 'off';
            app.DropDown_2.Value = 'Kinematic viscosity';
            app.Viscosity_2 = uieditfield(app.PipeFlowUIFigure, 'numeric');
            app.Viscosity_2.ValueChangedFcn = createCallbackFcn(app, @Viscosity_2ValueChanged, true);
            app.Viscosity_2.Tag = 'Case 2';
            app.Viscosity_2.Enable = 'off';
            app.Viscosity_2.Visible = 'off';
            app.ViscosityLabel_2 = uilabel(app.PipeFlowUIFigure);
            app.ViscosityLabel_2.Tag = 'Case 2';
            app.ViscosityLabel_2.Enable = 'off';
            app.ViscosityLabel_2.Visible = 'off';
            app.ViscosityLabel_2.Text = 'm^2/s';
            app.CircularprofileCheckBox = uicheckbox(app.PipeFlowUIFigure);
            app.CircularprofileCheckBox.ValueChangedFcn = createCallbackFcn(app, @CircularprofileCheckBoxValueChanged, true);
            app.CircularprofileCheckBox.Visible = 'off';
            app.CircularprofileCheckBox.Text = 'Circular profile';
            app.CircularprofileCheckBox.Value = true;
            app.Area_2 = uieditfield(app.PipeFlowUIFigure, 'numeric');
            app.Area_2.ValueChangedFcn = createCallbackFcn(app, @Area_2ValueChanged, true);
            app.Area_2.Enable = 'off';
            app.Area_2.Visible = 'off';
            app.FlowdiameterLabel = uilabel(app.PipeFlowUIFigure);
            app.FlowdiameterLabel.Visible = 'off';
            app.FlowdiameterLabel.Text = 'Flow diameter';
            app.Diameter_2 = uieditfield(app.PipeFlowUIFigure, 'numeric');
            app.Diameter_2.Visible = 'off';
            app.mLabel_3_2 = uilabel(app.PipeFlowUIFigure);
            app.mLabel_3_2.Tag = 'Case 2';
            app.mLabel_3_2.Visible = 'off';
            app.mLabel_3_2.Text = 'm';
            app.Perimeter_2 = uieditfield(app.PipeFlowUIFigure, 'numeric');
            app.Perimeter_2.ValueChangedFcn = createCallbackFcn(app, @Perimeter_2ValueChanged, true);
            app.Perimeter_2.Enable = 'off';
            app.Perimeter_2.Visible = 'off';
            app.AreaLabel_2 = uilabel(app.PipeFlowUIFigure);
            app.AreaLabel_2.Enable = 'off';
            app.AreaLabel_2.Visible = 'off';
            app.AreaLabel_2.Text = 'Area';
            app.WettedperimeterLabel_2 = uilabel(app.PipeFlowUIFigure);
            app.WettedperimeterLabel_2.Enable = 'off';
            app.WettedperimeterLabel_2.Visible = 'off';
            app.WettedperimeterLabel_2.Text = 'Wetted perimeter';
            app.mSqLabel_2 = uilabel(app.PipeFlowUIFigure);
            app.mSqLabel_2.Tag = 'Case 2';
            app.mSqLabel_2.Enable = 'off';
            app.mSqLabel_2.Visible = 'off';
            app.mSqLabel_2.Text = 'm^2';
            app.mLabel_4_2 = uilabel(app.PipeFlowUIFigure);
            app.mLabel_4_2.Tag = 'Case 2';
            app.mLabel_4_2.Enable = 'off';
            app.mLabel_4_2.Visible = 'off';
            app.mLabel_4_2.Text = 'm';
            app.EquivalentsandroughnessLabel_2 = uilabel(app.PipeFlowUIFigure);
            app.EquivalentsandroughnessLabel_2.Visible = 'off';
            app.EquivalentsandroughnessLabel_2.Text = 'Equivalent sand roughness';
            app.FlowLabel_2 = uilabel(app.PipeFlowUIFigure);
            app.FlowLabel_2.Tag = 'Case 3';
            app.FlowLabel_2.Enable = 'off';
            app.FlowLabel_2.Visible = 'off';
            app.FlowLabel_2.Text = '';
            app.PipelengthLabel_1 = uilabel(app.PipeFlowUIFigure);
            app.PipelengthLabel_1.Tag = 'Case 2';
            app.PipelengthLabel_1.Text = 'Pipe length';
            app.FullyturbulentflowCheckBox_1 = uicheckbox(app.PipeFlowUIFigure);
            app.FullyturbulentflowCheckBox_1.ValueChangedFcn = createCallbackFcn(app, @FullyturbulentflowCheckBox_1ValueChanged, true);
            app.FullyturbulentflowCheckBox_1.Tag = 'Case 2';
            app.FullyturbulentflowCheckBox_1.Text = 'Fully turbulent flow';
            app.FullyturbulentflowCheckBox_1.Value = true;
            app.Ks_1 = uieditfield(app.PipeFlowUIFigure, 'numeric');
            app.Ks_1.Tag = 'Case 2';
            app.PipeLength_1 = uieditfield(app.PipeFlowUIFigure, 'numeric');
            app.PipeLength_1.Tag = 'Case 2';
            app.mLabel_1 = uilabel(app.PipeFlowUIFigure);
            app.mLabel_1.Tag = 'Case 2';
            app.mLabel_1.Text = 'm';
            app.mmLabel_1 = uilabel(app.PipeFlowUIFigure);
            app.mmLabel_1.Tag = 'Case 2';
            app.mmLabel_1.Text = 'mm';
            app.DensityLabel_1 = uilabel(app.PipeFlowUIFigure);
            app.DensityLabel_1.Tag = 'Case 2';
            app.DensityLabel_1.Enable = 'off';
            app.DensityLabel_1.Text = 'Density';
            app.Density_1 = uieditfield(app.PipeFlowUIFigure, 'numeric');
            app.Density_1.Tag = 'Case 2';
            app.Density_1.Enable = 'off';
            app.kgm3Label_1 = uilabel(app.PipeFlowUIFigure);
            app.kgm3Label_1.Tag = 'Case 2';
            app.kgm3Label_1.Enable = 'off';
            app.kgm3Label_1.Text = 'kg/m^3';
            app.DropDown_1 = uidropdown(app.PipeFlowUIFigure);
            app.DropDown_1.Items = {'Reynold''s number', 'Dynamic viscosity', 'Kinematic viscosity'};
            app.DropDown_1.ValueChangedFcn = createCallbackFcn(app, @DropDown_1ValueChanged, true);
            app.DropDown_1.Tag = 'Case 2';
            app.DropDown_1.Enable = 'off';
            app.DropDown_1.Value = 'Kinematic viscosity';
            app.Viscosity_1 = uieditfield(app.PipeFlowUIFigure, 'numeric');
            app.Viscosity_1.ValueChangedFcn = createCallbackFcn(app, @Viscosity_1ValueChanged, true);
            app.Viscosity_1.Tag = 'Case 2';
            app.Viscosity_1.Enable = 'off';
            app.ViscosityLabel_1 = uilabel(app.PipeFlowUIFigure);
            app.ViscosityLabel_1.Tag = 'Case 2';
            app.ViscosityLabel_1.Enable = 'off';
            app.ViscosityLabel_1.Text = 'm^2/s';
            app.CircularProfileCheckBox_1 = uicheckbox(app.PipeFlowUIFigure);
            app.CircularProfileCheckBox_1.ValueChangedFcn = createCallbackFcn(app, @CircularProfileCheckBox_1ValueChanged, true);
            app.CircularProfileCheckBox_1.Text = 'Circular Profile';
            app.CircularProfileCheckBox_1.Value = true;
            app.Area_1 = uieditfield(app.PipeFlowUIFigure, 'numeric');
            app.Area_1.ValueChangedFcn = createCallbackFcn(app, @Area_1ValueChanged, true);
            app.Area_1.Enable = 'off';
            app.mLabel_3_1 = uilabel(app.PipeFlowUIFigure);
            app.mLabel_3_1.Tag = 'Case 2';
            app.mLabel_3_1.Text = 'm';
            app.Perimeter_1 = uieditfield(app.PipeFlowUIFigure, 'numeric');
            app.Perimeter_1.ValueChangedFcn = createCallbackFcn(app, @Perimeter_1ValueChanged, true);
            app.Perimeter_1.Enable = 'off';
            app.AreaLabel_1 = uilabel(app.PipeFlowUIFigure);
            app.AreaLabel_1.Enable = 'off';
            app.AreaLabel_1.Text = 'Area';
            app.WettedperimeterLabel_1 = uilabel(app.PipeFlowUIFigure);
            app.WettedperimeterLabel_1.Enable = 'off';
            app.WettedperimeterLabel_1.Text = 'Wetted perimeter';
            app.mSqLabel_1 = uilabel(app.PipeFlowUIFigure);
            app.mSqLabel_1.Tag = 'Case 2';
            app.mSqLabel_1.Enable = 'off';
            app.mSqLabel_1.Text = 'm^2';
            app.mLabel_4_1 = uilabel(app.PipeFlowUIFigure);
            app.mLabel_4_1.Tag = 'Case 2';
            app.mLabel_4_1.Enable = 'off';
            app.mLabel_4_1.Text = 'm';
            app.FlowDiameterLabel_1 = uilabel(app.PipeFlowUIFigure);
            app.FlowDiameterLabel_1.Text = 'Flow Diameter';
            app.Diameter_1 = uieditfield(app.PipeFlowUIFigure, 'numeric');
            app.EquivalentsandroughnessLabel_1 = uilabel(app.PipeFlowUIFigure);
            app.EquivalentsandroughnessLabel_1.Text = 'Equivalent sand roughness';
            app.FlowLabel_1 = uilabel(app.PipeFlowUIFigure);
            app.FlowLabel_1.Tag = 'Case 3';
            app.FlowLabel_1.Enable = 'off';
            app.FlowLabel_1.Visible = 'off';
            app.FlowLabel_1.Text = '';
            app.DropDownFlow_1 = uidropdown(app.PipeFlowUIFigure);
            app.DropDownFlow_1.Items = {'Volume flow rate', 'Flow velocity', 'Mass flow rate'};
            app.DropDownFlow_1.ValueChangedFcn = createCallbackFcn(app, @DropDownFlow_1ValueChanged, true);
            app.DropDownFlow_1.Tag = 'Case 3';
            app.DropDownFlow_1.Value = 'Volume flow rate';
            app.VorQorM_1 = uieditfield(app.PipeFlowUIFigure, 'numeric');
            app.VorQorM_1.Tag = 'Case 3';
            app.unitLabel_1 = uilabel(app.PipeFlowUIFigure);
            app.unitLabel_1.Tag = 'Case 3';
            app.unitLabel_1.Text = 'm^3/s';
            app.ReynoldsnumberCheckBox_3 = uicheckbox(app.PipeFlowUIFigure);
            app.ReynoldsnumberCheckBox_3.ValueChangedFcn = createCallbackFcn(app, @ReynoldsnumberCheckBox_3ValueChanged, true);
            app.ReynoldsnumberCheckBox_3.Enable = 'off';
            app.ReynoldsnumberCheckBox_3.Visible = 'off';
            app.ReynoldsnumberCheckBox_3.Text = 'Reynold''s number';
            app.Ks_3 = uieditfield(app.PipeFlowUIFigure, 'numeric');
            app.Ks_3.Visible = 'off';
            app.EquivalentsandroughnessLabel_3 = uilabel(app.PipeFlowUIFigure);
            app.EquivalentsandroughnessLabel_3.Visible = 'off';
            app.EquivalentsandroughnessLabel_3.Text = 'Equivalent sand roughness';
            app.Solve_1 = uibutton(app.PipeFlowUIFigure, 'push');
            app.Solve_1.ButtonPushedFcn = createCallbackFcn(app, @Solve_1ButtonPushed, true);
            app.Solve_1.Text = 'Calculate head loss';
            app.Solve_2 = uibutton(app.PipeFlowUIFigure, 'push');
            app.Solve_2.ButtonPushedFcn = createCallbackFcn(app, @Solve_2ButtonPushed, true);
            app.Solve_2.Visible = 'off';
            app.Solve_2.Text = 'Calculate volume flow rate';
            app.Solve_3 = uibutton(app.PipeFlowUIFigure, 'push');
            app.Solve_3.ButtonPushedFcn = createCallbackFcn(app, @Solve_3ButtonPushed, true);
            app.Solve_3.Visible = 'off';
            app.Solve_3.Text = 'Calculate Flow Diameter';
            app.Result_1 = uieditfield(app.PipeFlowUIFigure, 'numeric');
            app.Result_1.Editable = 'off';
            app.Result_2 = uieditfield(app.PipeFlowUIFigure, 'numeric');
            app.Result_2.Editable = 'off';
            app.Result_2.Visible = 'off';
            app.Result_3 = uieditfield(app.PipeFlowUIFigure, 'numeric');
            app.Result_3.Editable = 'off';
            app.Result_3.Visible = 'off';
            app.mLabel_4 = uilabel(app.PipeFlowUIFigure);
            app.mLabel_4.Text = 'm';
            app.m3sLabel = uilabel(app.PipeFlowUIFigure);
            app.m3sLabel.Visible = 'off';
            app.m3sLabel.Text = 'm^3/s';
            app.MinorHeadlossCoefficientLabel = uilabel(app.PipeFlowUIFigure);
            app.MinorHeadlossCoefficientLabel.Text = 'Minor Head loss Coefficient';
            app.ComponentsListBoxLabel = uilabel(app.PipeFlowUIFigure);
            app.ComponentsListBoxLabel.Text = 'Component';
            app.Components = uilistbox(app.PipeFlowUIFigure);
            app.Components.Items = {};
            app.Components.ValueChangedFcn = createCallbackFcn(app, @ComponentsValueChanged, true);
            app.Components.Value = {};
            app.Types = uilistbox(app.PipeFlowUIFigure);
            app.Types.Items = {};
            app.Types.Value = {};
            app.TypeLabel = uilabel(app.PipeFlowUIFigure);
            app.TypeLabel.Text = 'Type';
            app.KLabel = uilabel(app.PipeFlowUIFigure);
            app.KLabel.Text = 'K';
            app.TypeList = uitree(app.PipeFlowUIFigure);
            app.TypeList.SelectionChangedFcn = createCallbackFcn(app, @TypeListSelectionChanged, true);
            app.PipeenteranceNode = uitreenode(app.TypeList);
            app.PipeenteranceNode.Text = 'Pipe enterance';
            app.ContractionNode = uitreenode(app.TypeList);
            app.ContractionNode.Text = 'Contraction';
            app.ExpansionNode = uitreenode(app.TypeList);
            app.ExpansionNode.Text = 'Expansion';
            app.degreemiterbendNode = uitreenode(app.TypeList);
            app.degreemiterbendNode.Text = '90-degree miter bend';
            app.degreesmoothbendNode = uitreenode(app.TypeList);
            app.degreesmoothbendNode.Text = '90-degree smooth bend';
            app.ThreadedpipefittingsNode = uitreenode(app.TypeList);
            app.ThreadedpipefittingsNode.Text = 'Threaded pipe fittings';
            app.GlobevalvewideopenNode = uitreenode(app.ThreadedpipefittingsNode);
            app.GlobevalvewideopenNode.Text = 'Globe valve (wide open)';
            app.AnglevalvewideopenNode = uitreenode(app.ThreadedpipefittingsNode);
            app.AnglevalvewideopenNode.Text = 'Angle valve (wide open)';
            app.GatevalvewideopenNode = uitreenode(app.ThreadedpipefittingsNode);
            app.GatevalvewideopenNode.Text = 'Gate valve (wide open)';
            app.GatevalvehalfopenNode = uitreenode(app.ThreadedpipefittingsNode);
            app.GatevalvehalfopenNode.Text = 'Gate valve (half open)';
            app.ReturnbendNode = uitreenode(app.ThreadedpipefittingsNode);
            app.ReturnbendNode.Text = 'Return bend';
            app.degreeelbowNode = uitreenode(app.ThreadedpipefittingsNode);
            app.degreeelbowNode.Text = '90-degree elbow';
            app.degreeelbowNode_2 = uitreenode(app.ThreadedpipefittingsNode);
            app.degreeelbowNode_2.Text = '45-degree elbow';
            app.TeeNode = uitreenode(app.ThreadedpipefittingsNode);
            app.TeeNode.Text = 'Tee';
            app.StraightthroughflowNode = uitreenode(app.TeeNode);
            app.StraightthroughflowNode.Text = 'Straight-through flow';
            app.SideoutletflowNode = uitreenode(app.TeeNode);
            app.SideoutletflowNode.Text = 'Side-outlet flow';
            app.KList = uilistbox(app.PipeFlowUIFigure);
            app.KList.Items = {};
            app.KList.Value = {};
            app.TotalHeadlossCoefficientKEditFieldLabel = uilabel(app.PipeFlowUIFigure);
            app.TotalHeadlossCoefficientKEditFieldLabel.Text = 'Total Head loss Coefficient K';
            app.TotalK = uieditfield(app.PipeFlowUIFigure, 'numeric');
            app.ComponentSelectionLabel = uilabel(app.PipeFlowUIFigure);
            app.ComponentSelectionLabel.Text = 'Component Selection';
            app.TypeLabel_2 = uilabel(app.PipeFlowUIFigure);
            app.TypeLabel_2.Text = 'Type';
            app.rdEditFieldLabel = uilabel(app.PipeFlowUIFigure);
            app.rdEditFieldLabel.Visible = 'off';
            app.rdEditFieldLabel.Text = 'r/d';
            app.rd = uieditfield(app.PipeFlowUIFigure, 'numeric');
            app.rd.Visible = 'off';
            app.rEditFieldLabel = uilabel(app.PipeFlowUIFigure);
            app.rEditFieldLabel.Enable = 'off';
            app.rEditFieldLabel.Visible = 'off';
            app.rEditFieldLabel.Text = 'r';
            app.r = uieditfield(app.PipeFlowUIFigure, 'numeric');
            app.r.ValueChangedFcn = createCallbackFcn(app, @rValueChanged, true);
            app.r.Enable = 'off';
            app.r.Visible = 'off';
            app.dEditFieldLabel = uilabel(app.PipeFlowUIFigure);
            app.dEditFieldLabel.Enable = 'off';
            app.dEditFieldLabel.Visible = 'off';
            app.dEditFieldLabel.Text = 'd';
            app.d = uieditfield(app.PipeFlowUIFigure, 'numeric');
            app.d.ValueChangedFcn = createCallbackFcn(app, @dValueChanged, true);
            app.d.Enable = 'off';
            app.d.Visible = 'off';
            app.CheckBox = uicheckbox(app.PipeFlowUIFigure);
            app.CheckBox.ValueChangedFcn = createCallbackFcn(app, @CheckBoxValueChanged, true);
            app.CheckBox.Visible = 'off';
            app.CheckBox.Text = '';
            app.CheckBox.Value = true;
            app.CheckBox_2 = uicheckbox(app.PipeFlowUIFigure);
            app.CheckBox_2.ValueChangedFcn = createCallbackFcn(app, @CheckBox_2ValueChanged, true);
            app.CheckBox_2.Visible = 'off';
            app.CheckBox_2.Text = '';
            app.CheckBox_2.Value = true;
            app.D1D2EditFieldLabel = uilabel(app.PipeFlowUIFigure);
            app.D1D2EditFieldLabel.Visible = 'off';
            app.D1D2EditFieldLabel.Text = 'D1/D2';
            app.D1EditFieldLabel = uilabel(app.PipeFlowUIFigure);
            app.D1EditFieldLabel.Enable = 'off';
            app.D1EditFieldLabel.Visible = 'off';
            app.D1EditFieldLabel.Text = 'D1';
            app.D1 = uieditfield(app.PipeFlowUIFigure, 'numeric');
            app.D1.ValueChangedFcn = createCallbackFcn(app, @D1ValueChanged, true);
            app.D1.Enable = 'off';
            app.D1.Visible = 'off';
            app.D2EditFieldLabel = uilabel(app.PipeFlowUIFigure);
            app.D2EditFieldLabel.Enable = 'off';
            app.D2EditFieldLabel.Visible = 'off';
            app.D2EditFieldLabel.Text = 'D2';
            app.D2 = uieditfield(app.PipeFlowUIFigure, 'numeric');
            app.D2.ValueChangedFcn = createCallbackFcn(app, @D2ValueChanged, true);
            app.D2.Enable = 'off';
            app.D2.Visible = 'off';
            app.CheckBox_3 = uicheckbox(app.PipeFlowUIFigure);
            app.CheckBox_3.ValueChangedFcn = createCallbackFcn(app, @CheckBox_3ValueChanged, true);
            app.CheckBox_3.Text = '';
            app.KLabel_2 = uilabel(app.PipeFlowUIFigure);
            app.KLabel_2.Enable = 'off';
            app.KLabel_2.Text = 'K';
            app.K = uieditfield(app.PipeFlowUIFigure, 'numeric');
            app.K.Enable = 'off';
            app.ComponentName = uieditfield(app.PipeFlowUIFigure, 'text');
            app.ComponentName.Value = 'Component';
            app.AddButton = uibutton(app.PipeFlowUIFigure, 'push');
            app.AddButton.ButtonPushedFcn = createCallbackFcn(app, @AddButtonPushed, true);
            app.AddButton.Text = 'Add';
            app.Kh = uieditfield(app.PipeFlowUIFigure, 'numeric');
            app.Kh.ValueChangedFcn = createCallbackFcn(app, @KhValueChanged, true);
            app.Kh.Editable = 'off';
            app.Kh.Enable = 'off';
            app.Kh.Visible = 'off';
            app.ThetaLabel = uilabel(app.PipeFlowUIFigure);
            app.ThetaLabel.Visible = 'off';
            app.ThetaLabel.Text = 'θ';
            app.Theta = uidropdown(app.PipeFlowUIFigure);
            app.Theta.Items = {'60°', '180°'};
            app.Theta.Visible = 'off';
            app.Theta.Value = '60°';
            app.D1D2 = uieditfield(app.PipeFlowUIFigure, 'numeric');
            app.D1D2.Visible = 'off';
            app.img_PipeEnterance = uiimage(app.PipeFlowUIFigure);
            app.img_PipeEnterance.Visible = 'off';
            app.img_PipeEnterance.ImageSource = 'Pipe_Enterance.png';
            app.img_Contraction = uiimage(app.PipeFlowUIFigure);
            app.img_Contraction.Visible = 'off';
            app.img_Contraction.ImageSource = 'Contraction.png';
            app.img_Expansion = uiimage(app.PipeFlowUIFigure);
            app.img_Expansion.Visible = 'off';
            app.img_Expansion.ImageSource = 'Expansion.png';
            app.img_SmoothBend = uiimage(app.PipeFlowUIFigure);
            app.img_SmoothBend.Visible = 'off';
            app.img_SmoothBend.ImageSource = 'Smooth_Bend.png';
            app.img_General = uiimage(app.PipeFlowUIFigure);
            app.img_General.ImageSource = 'General.png';
            app.ResetButton = uibutton(app.PipeFlowUIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.CheckBoxL = uicheckbox(app.PipeFlowUIFigure);
            app.CheckBoxL.ValueChangedFcn = createCallbackFcn(app, @CheckBoxLValueChanged, true);
            app.CheckBoxL.Enable = 'off';
            app.CheckBoxL.Tooltip = {'Flow must be laminar'};
            app.CheckBoxL.Text = 'Evaluate friction factor without equivalent sand roughness';
            app.RFlowLabel_3 = uilabel(app.PipeFlowUIFigure);
            app.RFlowLabel_3.Text = '';
            % Show the figure after all components are created
            app.PipeFlowUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = projectapp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.PipeFlowUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.PipeFlowUIFigure)
        end
    end
end